classdef gBATGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        gBATUIFigure                    matlab.ui.Figure
        PostprocessingMenu              matlab.ui.container.Menu
        MakeNewAtlasBasedOnRemovedROIsduringanalysisMenu  matlab.ui.container.Menu
        MultipleComparisonCorrectionAcrossAnalysesMenu  matlab.ui.container.Menu
        FDRMenu                         matlab.ui.container.Menu
        BonferroniMenu                  matlab.ui.container.Menu
        ConvertVolumeAtlasToFsaverageMenu  matlab.ui.container.Menu
        ConvertVolumeResultsToFsaverageMenu  matlab.ui.container.Menu
        RegionalMenu                    matlab.ui.container.Menu
        VoxelwiseMenu                   matlab.ui.container.Menu
        LoadROItissuedefinitionsButton  matlab.ui.control.StateButton
        DropDown                        matlab.ui.control.DropDown
        SecondBraindatafileorfolderEditField  matlab.ui.control.EditField
        SecondBraindatafileorfolderEditFieldLabel  matlab.ui.control.Label
        LoadButton_4                    matlab.ui.control.Button
        SelectButton                    matlab.ui.control.Button
        BehavioraldatafileEditField_2   matlab.ui.control.EditField
        DirectoryforstoringoutputsLabel  matlab.ui.control.Label
        DefinedatatotestPanel           matlab.ui.container.Panel
        MakebrainfigsButton             matlab.ui.control.StateButton
        Lamp                            matlab.ui.control.Lamp
        ResetsettingsButton             matlab.ui.control.Button
        StartButton                     matlab.ui.control.Button
        NuissanceListBox                matlab.ui.control.ListBox
        NuissanceListBoxLabel           matlab.ui.control.Label
        OutcomeListBox                  matlab.ui.control.ListBox
        OutcomeListBoxLabel             matlab.ui.control.Label
        PredictorListBox                matlab.ui.control.ListBox
        PredictorListBoxLabel           matlab.ui.control.Label
        GeneratedatahistogramDropDown   matlab.ui.control.DropDown
        GeneratedatahistogramDropDownLabel  matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
        AtlasniftifileoptionalEditField  matlab.ui.control.EditField
        AtlasniftifileoptionalLabel     matlab.ui.control.Label
        LoadButton_3                    matlab.ui.control.Button
        AdditionalargumentstopassButtonGroup  matlab.ui.container.ButtonGroup
        EditField                       matlab.ui.control.EditField
        OptionsforpvaluesandcorrectionsButtonGroup  matlab.ui.container.ButtonGroup
        BootstrappvaluesButton_2        matlab.ui.control.StateButton
        ThresholdforclustersignificancepEditField  matlab.ui.control.EditField
        ThresholdforclustersignificancepEditFieldLabel  matlab.ui.control.Label
        SavetemporaryfilesButton        matlab.ui.control.StateButton
        ThresholdforvoxelROIsignificancepEditField  matlab.ui.control.EditField
        ThresholdforvoxelROIsignificancepEditFieldLabel  matlab.ui.control.Label
        FDRButton                       matlab.ui.control.ToggleButton
        CorrectionbypermutedclustersizeButton  matlab.ui.control.ToggleButton
        NocorrectionButton              matlab.ui.control.ToggleButton
        MediationoptionsButtonGroup     matlab.ui.container.ButtonGroup
        UserobustOLSButton              matlab.ui.control.StateButton
        BiascorrectedbootstrappingButton  matlab.ui.control.StateButton
        AdditionaloptionsButtonGroup    matlab.ui.container.ButtonGroup
        ExcludevoxelswithlessthanEditField  matlab.ui.control.NumericEditField
        ExcludevoxelswithlessthanEditFieldLabel  matlab.ui.control.Label
        ExcludevoxelswithlessthanEditField_2  matlab.ui.control.NumericEditField
        ExcludevoxelswithlessthanEditFieldLabel_2  matlab.ui.control.Label
        LoadamaskButton                 matlab.ui.control.StateButton
        ZscoredataButton                matlab.ui.control.StateButton
        CPUparallelizationButton        matlab.ui.control.StateButton
        TailsforanalysisButtonGroup     matlab.ui.container.ButtonGroup
        PositiveButton                  matlab.ui.control.ToggleButton
        NegativeButton                  matlab.ui.control.ToggleButton
        BothButton_2                    matlab.ui.control.ToggleButton
        RelationshipstoconsiderButtonGroup  matlab.ui.container.ButtonGroup
        LinearandNonlinearButton        matlab.ui.control.ToggleButton
        LinearButton                    matlab.ui.control.ToggleButton
        graphicalBrainAssociationToolgBATLabel  matlab.ui.control.Label
        BraindatafileorfolderEditField  matlab.ui.control.EditField
        BraindatafileorfolderEditFieldLabel  matlab.ui.control.Label
        LoadButton_2                    matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        BehavioraldatafileEditField     matlab.ui.control.EditField
        BehavioraldatafileLabel         matlab.ui.control.Label
        AnalysistorunButtonGroup        matlab.ui.container.ButtonGroup
        MediationButton                 matlab.ui.control.ToggleButton
        PartialcorrelationButton        matlab.ui.control.ToggleButton
        CorrelationButton               matlab.ui.control.ToggleButton
        ContextMenu                     matlab.ui.container.ContextMenu
        ResetsettingsandallloadeddataMenu  matlab.ui.container.Menu
        ResetallloadeddataMenu          matlab.ui.container.Menu
        ContextMenu2                    matlab.ui.container.ContextMenu
        AddasecondsetofbrainmapsieforseparategreyandwhitemattermapsMenu  matlab.ui.container.Menu
    end


    properties (Access = private)
        inBeh = [];
        inBrain = [];
        inBrain2 = [];
        inBrainPth2 = [];
        inBrainInfo = [];
        inROIInfo = [];
        inROI = [];
        inROIPath = [];
        inROIMetaData = [];
        inBrainSubs = [];
        inBrainSubs2 = [];
        inBehSubs = [];
        %brain = [];
        inBrainBackup = [];
        inROIBackup = [];
        inBehPth = [];
        inBrainPth = [];
        brainMask = [];
        oPth = [];
        allowIPT = true;
        allowMed = true;
        allowDist = true;
        allowStatsML = true;
        allowFDR = true;
        pass = true;
        perms = 500;
        boots = 500;
        defaults = struct('analysisType','correlation','relation','linear','tail','both','args',[],'pCorr','FDR','tempSave',false,'vThresh',[],'cThresh',[],...
            'cpu',false,'norm',false,'cleanROI',true,'mask',[],'propOverlap',0,'meanThresh',0,'medBootT',true,'robustOLS',true,'bootP',true,'keepStat','Mediation t or z','nPerms',500,'nBoot',500,'nobBoot',true)
    end

    methods (Access = private)


        function [] = toolboxUpdateOptions(app)
            if ~app.allowMed
                app.AnalysistorunButtonGroup.Buttons(3).Enable = 'off';
                app.BiascorrectedbootstrappingButton.Enable = 'off';
                app.MediationButton.Enable = 'off';
                app.MediationcoeffButton_2.Enable = 'off';
                app.MediationtButton.Enable = 'off';
                app.MediationcoeffButton.Enable = 'off';
                app.MediationtButton.Enable = 'off';
                app.UserobustOLSButton.Enable = 'off';
                app.AllButton.Enable = 'off';
                app.SavetemporaryfilesButton.Enable = 'off';
                app.MediationoptionsButtonGroup.Enable = 'off';
                app.CorrelationButton.Value = true;
                app.LinearButton.Value = true;
            end

            if ~app.allowIPT
                app.OptionsforpvaluesandcorrectionsButtonGroup.Buttons(2).Enable = 'off';
                app.BraindatafileorfolderEditField.Placeholder = 'Path to a .mat file';
                app.AtlasniftifileoptionalEditField.Placeholder = 'Path to a .mat file';
                app.ThresholdforclustersignificancepEditField.Enable = 'off';
                app.ThresholdforclustersignificancepEditFieldLabel.Enable = 'off';
            end

            if ~app.allowDist
                app.LinearandNonlinearButton.Enable = 'off';
                app.LinearButton.Value = true;
            end

            if ~app.allowFDR
                app.FDRButton.Enable = 'off';
                app.NocorrectionButton.Value = true;
            end

            if ~app.allowStatsML
                if app.CorrelationButton.Value || app.PartialcorrelationButton.Value
                    app.LinearButton.Enable = 'off';
                    app.LinearButton.Value = false;
                    app.PartialcorrelationButton.Enable = 'off';
                    app.PartialcorrelationButton.Value = false;
                    app.BootstrappvaluesButton_2.Enable = 'off';
                    app.BootstrappvaluesButton_2.Value = false;
                elseif app.allowMed
                    app.LinearButton.Enable = 'on';
                    app.LinearButton.Value = true;
                    app.PartialcorrelationButton.Enable = 'on';
                    app.PartialcorrelationButton.Value = true;
                    app.BootstrappvaluesButton_2.Enable = 'on';
                    app.BootstrappvaluesButton_2.Value = true;
                end
            end

            % special cases...
            % if selected correlation, nuissance must be off
            if app.AnalysistorunButtonGroup.Buttons(1).Value
                app.NuissanceListBox.Enable = 'off';
                app.NuissanceListBoxLabel.Enable = 'off';
            else
                app.NuissanceListBox.Enable = 'on';
                app.NuissanceListBoxLabel.Enable = 'on';
            end

            % if correlation or partial correlation, no outcome
            if app.AnalysistorunButtonGroup.Buttons(1).Value || app.AnalysistorunButtonGroup.Buttons(2).Value
                app.OutcomeListBox.Enable = 'off';
                app.OutcomeListBoxLabel.Enable = 'off';
            else
                app.OutcomeListBox.Enable = 'on';
                app.OutcomeListBoxLabel.Enable = 'on';
            end

            % if mediation, turn off linear and nonlinear and turn on
            % mediation options...
            if app.AnalysistorunButtonGroup.Buttons(3).Value
                app.RelationshipstoconsiderButtonGroup.Buttons(1).Value = true;
                app.LinearandNonlinearButton.Enable = 'off';
                app.MediationoptionsButtonGroup.Enable = 'on';
            else
                app.LinearandNonlinearButton.Enable = 'on';
                app.MediationoptionsButtonGroup.Enable = 'off';
            end

            % if distance correlation, turn off Tails
            if app.LinearandNonlinearButton.Value
                app.BothButton_2.Value = 1;
                app.NegativeButton.Enable = 'off';
                app.PositiveButton.Enable = 'off';
            else
                app.NegativeButton.Enable = 'on';
                app.PositiveButton.Enable = 'on';
            end

            % if no corr or FDR, turn off cluster threshold and save tmp
            % files
            if ~app.OptionsforpvaluesandcorrectionsButtonGroup.Buttons(2).Value
                app.ThresholdforclustersignificancepEditField.Enable = 'off';
                app.ThresholdforclustersignificancepEditFieldLabel.Enable = 'off';
                app.SavetemporaryfilesButton.Enable = 'off';
            else
                app.ThresholdforclustersignificancepEditField.Enable = 'on';
                app.ThresholdforclustersignificancepEditFieldLabel.Enable = 'on';
                app.SavetemporaryfilesButton.Enable = 'on';
            end

            % if brain data exists and it is rois, turn off cluster thresh
            % and save tmp and turn on empty rois, else turn all of these
            % off (also "remove" empty roi option)
            if ~isempty(app.inROI) && ~isempty(app.inBrain)
                if ~isempty(app.inROI)
                    app.ThresholdforclustersignificancepEditField.Enable = 'off';
                    app.ThresholdforclustersignificancepEditFieldLabel.Enable = 'off';
                    app.SavetemporaryfilesButton.Enable = 'off';
                    app.CorrectionbypermutedclustersizeButton.Enable = 'off';
                    %app.RemoveemptyROIsButton.Enable = 'on';
                else
                    %app.RemoveemptyROIsButton.Enable = 'off';
                    if app.allowIPT
                        app.ThresholdforclustersignificancepEditField.Enable = 'off';
                        app.ThresholdforclustersignificancepEditFieldLabel.Enable = 'off';
                        app.SavetemporaryfilesButton.Enable = 'off';
                        app.CorrectionbypermutedclustersizeButton.Enable = 'off';
                    end
                end
            elseif isempty(app.inROI)
                %app.RemoveemptyROIsButton.Enable = 'off';
            end
            
            % you need to check if inBrain is loaded but there is no
            % template...
            if ~isempty(app.inBrain) && isempty(app.inBrainInfo)
                % you need to select an example nifti for the header
                % info...
                uialert(app.gBATUIFigure,'It looks like you have loaded in your brain data as a mat file but we need one nifti file corresponding to your data to write files. You will be prompted to select this file.','Data missing');
                [f,pth] = uigetfile({'*.nii;*.gz'},[pwd],'Please select an example nifti file that matches your brain data...');

                if sum(f) ~= 0
                    s = filesep;
                    f = [pth f];
                    [f1, ~, ext] = fileparts(f);
                    app.inBrainPth = f;
                    tmpinfo = niftiinfo(f);
                    v = niftiread(tmpinfo);
                    app.inBrainInfo = tmpinfo;

                else
                    uialert(app.gBATUIFigure,'Looks like you failed to provide a nifti file...analysis will fail','Data missing');
                end
            end

            if ~isempty(app.inROI) && isempty(app.inROIInfo)
                % you need to select an example nifti for the header
                % info...
                uialert(app.gBATUIFigure,'It looks like you have loaded in your ROI data as a mat file but we need one nifti file corresponding to your data to write files. You will be prompted to select this file.','Data missing');
                [f,pth] = uigetfile({'*.nii;*.gz'},[pwd],'Please select an example nifti file that matches your ROI data...');

                if sum(f) ~= 0
                    s = filesep;
                    f = [pth f];
                    [f1, ~, ext] = fileparts(f);
                    app.inROIPath = f;
                    tmpinfo = niftiinfo(f);
                    v = niftiread(tmpinfo);
                    app.inROIInfo = tmpinfo;
                else
                    uialert(app.gBATUIFigure,'Looks like you failed to provide a nifti file for the ROI...analysis will fail','Data missing');
                end
            end

            if app.BootstrappvaluesButton_2.Value
                app.BiascorrectedbootstrappingButton.Value = false;
                app.UserobustOLSButton.Value = false;
                app.CPUparallelizationButton.Value = false;
            end

            if app.BiascorrectedbootstrappingButton.Value
                app.BootstrappvaluesButton_2.Value = false;
            end

            if app.UserobustOLSButton.Value
               app.BiascorrectedbootstrappingButton.Value = true;
               app.CorrectionbypermutedclustersizeButton.Value = false;
            end

            % % if not keeping all data, use that as the cluster correction
            % % value
            % if app.StatstokeepButtonGroup.Buttons(1).Value || app.StatstokeepButtonGroup.Buttons(2).Value
            %     if app.StatstokeepButtonGroup.Buttons(1).Value
            %         app.MediationtorzButton.Value = true;
            %         app.StattofocusonButtonGroup.Enable = 'off';
            %     elseif app.StatstokeepButtonGroup.Buttons(2).Value
            %         app.MediationcoeffButton_2.Value = true;
            %         app.StattofocusonButtonGroup.Enable = 'off';
            %     end
            % elseif app.StatstokeepButtonGroup.Buttons(3).Value
            %     app.StattofocusonButtonGroup.Enable = 'on';
            %     %app.MediationtorzButton.Value = true;
            % end

            % check if brain data is input, behavioral data exists, if
            % passing...

            % check if any selected options are zeroed out. If so, not
            % passing...
            if strcmpi(app.MediationButton.Enable,'off') && strcmpi(app.PartialcorrelationButton.Enable,'off') && strcmpi(app.CorrelationButton.Enable,'off')
                uialert(app.gBATUIFigure,'You cannot run the analysis! Please download some toolboxes first','Toolboxes missing');
                app.pass = false;
            end

            % change lamp to show if voxelwise or ROI analysis as currently
            % set up
            if ~isempty(app.inROI) && ~isempty(app.inBrain)
                app.Lamp.Color = [0.1490    0.5686    0.5412];
            elseif isempty(app.inROI)
                app.Lamp.Color = [0.81 0.32 0.48];
            end

            % if there is no behavioral data, remove axes and cleanup
            % tables
            if isempty(app.inBeh)
                cla(app.UIAxes,'reset')
                app.GeneratedatahistogramDropDown.Items = {'Column 1';'Column2';'Column3'};
                app.PredictorListBox.Items = {'Column 1';'Column2';'Column3'};
                app.OutcomeListBox.Items = {'Column 1';'Column2';'Column3'};
                app.NuissanceListBox.Items = {'Column 1';'Column2';'Column3'};
            end
        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            bd = which('vbm.m');
            [f1,~,~] = fileparts(bd);
            addpath(genpath(f1));
            
            %Check toolboxes for availability
            tmp = ver;
            id = find(ismember({tmp.Name},'Image Processing Toolbox'));
            v = tmp(id).Release;

            if isempty(id)
                uialert(app.gBATUIFigure,'You do not have matlabs image processing toolbox installed. This will disable cluster correction and saving/loading of nifti files.','Toolbox missing');
                app.allowIPT = false;
            else
                y = str2num(v(end-3:end-2));
                if y < 17 || (y == 17 && strcmpi(v(end-1:end-1),'a'))
                    uialert(app.gBATUIFigure,'Your version of the image processing toolbox is too old. Please install something from 2017b or later. This will disable cluster correction and saving/loading of nifti files.','Toolbox missing');
                    app.allowIPT = false;
                end
            end

            id = find(ismember({tmp.Name},'Statistics and Machine Learning Toolbox'));
            v = tmp(id).Release;
            if isempty(id)
                uialert(app.gBATUIFigure,'You do not have matlabs statistics and machine learning toolbox. This will disable linear correlation options','Toolbox missing');
                app.allowStatsML = false;
            end

            id = find(ismember({tmp.Name},'Bioinformatics Toolbox'));
            v = tmp(id).Release;
            if isempty(id)
                uialert(app.gBATUIFigure,'You do not have matlabs bioinformatics toolbox. This will disable FDR correction options','Toolbox missing');
                app.allowStatsML = false;
            end

            if ~exist('mediation.m','file')
                app.allowMed = false;
                uialert(app.gBATUIFigure,'Cannot find the mediation toolbox. Please download it and make sure it is added to your path, otherwise some options will be disabled: https://github.com/canlab/MediationToolbox','Toolbox missing');
            end

            if ~exist('bcdistcorr.m','file') || ~exist('pdcPerm.m','file')
                app.allowDist = false;
                uialert(app.gBATUIFigure,'Cannot find the partial distance correlation toolbox. Please download it and make sure it is added to your path, otherwise some options will be disabled: https://github.com/alexteghipco/partialDistanceCorrelation','Toolbox missing');
            end
            toolboxUpdateOptions(app)
        end

        % Button pushed function: LoadButton
        function loadBeh(app, event)
            [f,p] = uigetfile({'*.csv;*.txt;*.xlsx;*.xls;*.dat;*.dlm;*.tab;*.mat'},'Please select your behavioral data');
            if f ~= 0
                app.inBehPth = fullfile(p, f);
                app.BehavioraldatafileEditField.Value = app.inBehPth;

                [~,~,ext] = fileparts(f);
                if ~strcmpi(ext,'.mat')
                    app.inBeh = readtable(app.inBehPth);
                else
                    tmp = load(app.inBehPth);
                    list = fieldnames(tmp);
                    if length(list) > 1
                        [idx,tf] = listdlg('ListString',list,'PromptString',{'Select variable from your mat file', 'that represent your behavioral','data'},'SelectionMode','single');
                    elseif length(list) == 1
                        idx = 1;
                        tf = true;
                    end
                    if tf
                        app.inBeh = tmp.(list{idx(1)});
                        if ~istable(app.inBeh)
                            app.inBeh = array2table(app.inBeh);
                        end
                    end
                end
                app.PredictorListBox.Items = app.inBeh.Properties.VariableNames;
                app.PredictorListBox.Value = app.PredictorListBox.Items{1};
                app.OutcomeListBox.Items = app.inBeh.Properties.VariableNames;
                app.OutcomeListBox.Value = app.OutcomeListBox.Items{1};
                app.NuissanceListBox.Items = app.inBeh.Properties.VariableNames;
                app.NuissanceListBox.Value = app.NuissanceListBox.Items{1};
                app.GeneratedatahistogramDropDown.Items = app.inBeh.Properties.VariableNames;
                tog = round(length(app.GeneratedatahistogramDropDown.Items)./2);
                app.GeneratedatahistogramDropDown.Value = app.GeneratedatahistogramDropDown.Items(tog);

                tmpdata = table2array(app.inBeh(:,tog));
                try
                    h = histfit(app.UIAxes,tmpdata,round(length(tmpdata)*0.04),'kernel');
                    h(1).FaceColor = [0.8118    0.3176    0.4824];
                    h(1).EdgeColor = [0.8118    0.3176    0.4824];
                    h(2).Color = [0.1490    0.5686    0.5412];% [0.8118    0.3176    0.4824];
                    h(2).LineWidth = 5;
                    h(1).Parent.XLim = [floor(min(tmpdata)) ceil(max(tmpdata))];
                    app.UIAxes.Box = 'off';
                catch
                    try
                        h = histogram(app.UIAxes,tmpdata);
                        h.FaceColor = [0.8118    0.3176    0.4824];
                        h.EdgeColor = [0.8118    0.3176    0.4824];
                        h.FaceAlpha = 1;
                        h.Parent.XLim = [floor(min(tmpdata)) ceil(max(tmpdata))];
                        app.UIAxes.Box = 'off';
                    catch
                        disp('Could not plot your behavioral data on import--maybe the middle column is subject numbers?')
                    end
                end
            end
            toolboxUpdateOptions(app)
        end

        % Button pushed function: LoadButton_2
        function loadBrain(app, event)
            [f,pth] = uigetfile({'*.nii;*.gz;*.mat'},[pwd],'Please select a file from the folder that contains all of the niftis you would like to analyze or a mat file that contains your extracted images');

            if sum(f) ~= 0
                s = filesep;
                f = [pth f];
                [f1, ~, ext] = fileparts(f);

                if strcmpi(ext,'.nii') || strcmpi(ext,'.gz')
                    app.inBrainPth = f1;
                    app.BraindatafileorfolderEditField.Value = f1;
                    d = dir([app.inBrainPth s '*.nii*']);
                    sz = [];

                    p = uiprogressdlg(app.gBATUIFigure,'Title','Loading brain data',...
                        'Message','Starting up...','Cancelable','off');

                    for i = 1:length(d)
                        disp(['Working on loading file ' d(i).name ', which is file ' num2str(i) ' of ' num2str(length(d))])
                        p.Value = i/length(d);
                        p.Message = ['Working on loading file ' d(i).name ', which is file ' num2str(i) ' of ' num2str(length(d))];
                        [~,dd,ext] = fileparts(d(i).name);
                        if strcmpi(ext,'.nii')
                            app.inBrainSubs{i} = dd;
                        else
                            [~,app.inBrainSubs{i},~] = fileparts(dd);
                        end
                        tmpinfo = niftiinfo([d(i).folder s d(i).name]);
                        v = niftiread(tmpinfo);
                        if i == 1
                            sz = size(v);
                            app.inBrain(i,:) = v(:);
                            app.inBrainInfo = tmpinfo;
                        else
                            if sz == size(v)
                                app.inBrain(i,:) = v(:);
                            else
                                uialert(app.gBATUIFigure,'Some of the nifti files you have pointed to have different sizes. Please fix before continuing.','Data mismatch');
                                app.inBrainInfo = [];
                            end
                        end
                    end
                    delete(p)
                elseif strcmpi(ext,'.mat')
                    app.inBrainPth = f;
                    app.BraindatafileorfolderEditField.Value = f;
                    tmp = load(app.inBrainPth);
                    list = fieldnames(tmp);
                    if length(list) > 1
                        [idx,tf] = listdlg('ListString',list,'PromptString',{'Select variable from your mat file', 'that represent your brain','data'},'SelectionMode','single');
                        if tf
                            app.inBrain = tmp.(list{idx(1)});
                        end

                        [idx,tf] = listdlg('ListString',list,'PromptString',{'Now select variable from your mat file', 'that represent your sub','IDs'},'SelectionMode','single');
                        if tf
                            app.inBrainSubs = tmp.(list{idx(1)});
                        end
                    else
                        uialert(app.gBATUIFigure,'If loading brain data as mat file you must have separate subject name vector and brain matrix (i.e., at least 2 variables in the file)','Data missing');
                    end
                end

                % remove inBrain2 if it is we are loading data...
                app.inBrain2 = [];
                app.inBrainPth2 = [];
                app.inBrainSubs2 = [];
                app.inROIMetaData = [];
                app.SecondBraindatafileorfolderEditField.Visible = 'off';
                app.SecondBraindatafileorfolderEditFieldLabel.Visible = 'off';
                app.LoadButton_4.Visible = 'off';
                app.DropDown.Visible = 'off';
                app.LoadROItissuedefinitionsButton.Visible = 'off';
                app.SecondBraindatafileorfolderEditField.Value = '';
                %app.LoadButton_4.Value = false;
            end

            % check if there is a mask loaded in first...if so, apply it
            if ~isempty(app.brainMask)
                if max(app.brainMask) > length(app.inBrain,1)
                    uialert(app.gBATUIFigure,'Your already loaded in brain mask has a dimension mismatch. Removing the mask...','Data mismatch');
                    app.brainMask = [];
                    app.LoadamaskButton.Value = false;
                    if ~isempty(app.inROI)
                        uialert(app.gBATUIFigure,'You had an atlas loaded that worked with the mask but we assume the subject-level data is correct so we have removed the atlas as well','Data mismatch');
                        app.inROI = [];
                        app.inROIPath = [];
                        app.AtlasniftifileoptionalEditField.Value = [];
                    end
                    % else
                    %     app.inBrain = app.inBrain(mask,:);
                end
            end

            % if an ROI has already been loaded in, let's convert...
            if ~isempty(app.inROI)
                % first, check ROI/input data availability
                if isequal(length(app.inROI),size(app.inBrain,2))


                else
                    uialert(app.gBATUIFigure,'Your ROI and subject-level brain data have a dimension mismatch. Erasing ROI as we assume the subject-level data is correct','Data mismatch');
                    app.inROI = [];
                    app.inROIPath = [];
                    app.AtlasniftifileoptionalEditField.Value  = '';
                end
            end
            toolboxUpdateOptions(app)

            % if isempty(app.brainMask)
            %     % make a pre-mask backup of the data...
            %     app.inBrainBackup = app.inBrain;
            %     app.inROIBackup = app.inROI;
            % end
        end

        % Button pushed function: SelectButton
        function loadOut(app, event)
            [f] = uigetdir([pwd],'Please select a folder to output files into');
            if f ~= 0
                app.oPth = f;
                app.BehavioraldatafileEditField_2.Value = f;
                uialert(app.gBATUIFigure,'If you did not create a new folder--caution, we overwrite files!! If this is a problem select a different folder.','Warn about overwritting');
            end
            toolboxUpdateOptions(app)
        end

        % Button pushed function: LoadButton_3
        function loadAtlas(app, event)
            [f,pth] = uigetfile({'*.nii;*.gz'},[pwd],'Please select a file that contains your atlas (.nii, .nii.gz, or a variable from a .mat)');
            if sum(f) ~= 0
                s = filesep;
                f = [pth f];
                [~, ~, ext] = fileparts(f);
                app.inROIPath = f;
                app.AtlasniftifileoptionalEditField.Value = f;

                if strcmpi(ext,'.nii') || strcmpi(ext,'.gz')
                    tmpinfo = niftiinfo(f);
                    v = niftiread(tmpinfo);
                    app.inROIInfo = tmpinfo;
                    % elseif strcmpi(ext,'.mat')
                    %     tmp = load(f);
                    %     list = fieldnames(tmp);
                    %     if length(list) > 1
                    %         [idx,tf] = listdlg('ListString',list,'PromptString',{'Select variable from your mat file', 'that represent atlas','data'},'SelectionMode','single');
                    %         if tf
                    %             v = tmp.(list{idx(1)});
                    %         end
                else
                    uialert(app.gBATUIFigure,'Could not load your file...is it a nifti?','Data missing');
                    %v = tmp.(list{1});
                end

                % if brain is not empty, check that roi is same size...
                if ~isempty(app.inBrain)
                    if ~isequal(size(app.inBrain,2),length(v(:)))
                        uialert(app.gBATUIFigure,'The atlas nifti is a different dimensionality than the brain data you have already loaded in. Ignoring your atlas...','Data mismatch');
                        app.inROIPath = [];
                        app.AtlasniftifileoptionalEditField.Value  = '';
                    else
                        app.inROI = v(:);
                    end
                else
                    app.inROI = v(:);
                end

                % if roi and brain are same size or only roi exists check
                % against mask. Remove mask if does not fit roi
                if ~isempty(app.brainMask) && ~isempty(app.inROI)
                    if length(app.brainMask) > length(app.inROI)
                        uialert(app.gBATUIFigure,'Your already loaded in brain mask has a dimension mismatch. Removing the mask...','Data mismatch');
                        app.brainMask = [];
                        app.LoadamaskButton.Value = false;
                        % else
                        %     app.inROI = app.inROI(mask);
                    end
                end

                % if isempty(app.brainMask)
                %     % make a pre-mask backup of the data...
                %     app.inBrainBackup = app.inBrain;
                %     app.inROIBackup = app.inROI;
                % end
            end
            toolboxUpdateOptions(app)
        end

        % Value changed function: BehavioraldatafileEditField
        function changeBehPath(app, event)
            f = app.BehavioraldatafileEditField.Value;
            if ~isempty(f)
                if ~exist(app.BehavioraldatafileEditField.Value,'file')
                    uialert(app.gBATUIFigure,'Could not find your file, please check the path you have pasted in for the behavioral data file field','Load error');
                else
                    app.inBehPth = app.BehavioraldatafileEditField.Value;
                    [~,~,ext] = fileparts(app.inBehPth);
                    if ~strcmpi(ext,'.mat')
                        app.inBeh = readtable(app.inBehPth);
                    else
                        tmp = load(app.inBehPth);
                        list = fieldnames(tmp);
                        if length(list) > 1
                            [idx,tf] = listdlg('ListString',list,'PromptString',{'Select variable from your mat file', 'that represent your behavioral','data'},'SelectionMode','single');
                        elseif length(list) == 1
                            idx = 1;
                            tf = true;
                        end
                        if tf
                            app.inBeh = tmp.(list{idx(1)});
                            if ~istable(app.inBeh)
                                app.inBeh = array2table(app.inBeh);
                            end
                        end
                    end

                    app.PredictorListBox.Items = app.inBeh.Properties.VariableNames;
                    app.PredictorListBox.Value = app.PredictorListBox.Items{1};
                    app.OutcomeListBox.Items = app.inBeh.Properties.VariableNames;
                    app.OutcomeListBox.Value = app.OutcomeListBox.Items{1};
                    app.NuissanceListBox.Items = app.inBeh.Properties.VariableNames;
                    app.NuissanceListBox.Value = app.NuissanceListBox.Items{1};
                    app.GeneratedatahistogramDropDown.Items = app.inBeh.Properties.VariableNames;
                    tog = round(length(app.GeneratedatahistogramDropDown.Items)./2);
                    app.GeneratedatahistogramDropDown.Value = app.GeneratedatahistogramDropDown.Items(tog);

                    tmpdata = table2array(app.inBeh(:,tog));
                    try
                        h = histfit(app.UIAxes,tmpdata,round(length(tmpdata)*0.04),'kernel');
                        h(1).FaceColor = [0.8118    0.3176    0.4824];
                        h(1).EdgeColor = [0.8118    0.3176    0.4824];
                        h(2).Color = [0.1490    0.5686    0.5412];% [0.8118    0.3176    0.4824];
                        h(2).LineWidth = 5;
                        h(1).Parent.XLim = [floor(min(tmpdata)) ceil(max(tmpdata))];
                    catch
                        try
                            h = histogram(app.UIAxes,tmpdata);
                            h.FaceColor = [0.8118    0.3176    0.4824];
                            h.EdgeColor = [0.8118    0.3176    0.4824];
                            h.FaceAlpha = 1;
                            h.Parent.XLim = [floor(min(tmpdata)) ceil(max(tmpdata))];
                        catch
                            disp('Could not plot your behavioral data on import--maybe the middle column is subject numbers?')
                        end
                    end
                end
            else
                app.inBeh = [];
                app.inBehSubs = [];
                app.inBehPth = [];
            end
            toolboxUpdateOptions(app)
        end

        % Value changed function: BraindatafileorfolderEditField
        function changeBrainPath(app, event)
            f = app.BraindatafileorfolderEditField.Value;
            if ~isempty(f)
                s = filesep;
                if isfolder(f)
                    d = dir([f s '*.nii*']);
                    app.inBrainPth = f;
                    if length(d) == 0
                        uialert(app.gBATUIFigure,'Could not find any nifti files in your directory.','Missing data');
                    else
                        app.inBrainPth = f;
                        sz = [];
                        p = uiprogressdlg(app.gBATUIFigure,'Title','Loading brain data',...
                            'Message','Starting up...','Cancelable','off');

                        for i = 1:length(d)
                            disp(['Working on loading file ' d(i).name ', which is file ' num2str(i) ' of ' num2str(length(d))])
                            p.Value = i/length(d);
                            p.Message = ['Working on loading file ' d(i).name ', which is file ' num2str(i) ' of ' num2str(length(d))];
                            [~,dd,ext] = fileparts(d(i).name);
                            if strcmpi(ext,'.nii')
                                app.inBrainSubs{i} = dd;
                            else
                                [~,app.inBrainSubs{i},~] = fileparts(dd);
                            end
                            tmpinfo = niftiinfo([d(i).folder s d(i).name]);
                            app.inBrainInfo = tmpinfo;
                            v = niftiread(tmpinfo);
                            if i == 1
                                sz = size(v);
                                app.inBrain(i,:) = v(:);
                            else
                                if sz == size(v)
                                    app.inBrain(i,:) = v(:);
                                else
                                    uialert(app.gBATUIFigure,'Some of the nifti files you have pointed to have different sizes. Please fix before continuing.','Data mismatch');
                                end
                            end
                        end
                        delete(p)
                    end
                else
                    id = strfind(f,'*');
                    if ~isempty(id)
                        d = dir(f);
                        if length(d) == 0
                            uialert(app.gBATUIFigure,'Could not find any nifti files in your directory based on your regular expression.','Missing data');
                        else
                            app.inBrainPth = f;
                            sz = [];
                            p = uiprogressdlg(app.gBATUIFigure,'Title','Loading brain data',...
                                'Message','Starting up...','Cancelable','off');

                            for i = 1:length(d)
                                disp(['Working on loading file ' d(i).name ', which is file ' num2str(i) ' of ' num2str(length(d))])
                                p.Value = i/length(d);
                                p.Message = ['Working on loading file ' d(i).name ', which is file ' num2str(i) ' of ' num2str(length(d))];
                                [~,dd,ext] = fileparts(d(i).name);
                                if strcmpi(ext,'.nii')
                                    app.inBrainSubs{i} = dd;
                                else
                                    [~,app.inBrainSubs{i},~] = fileparts(dd);
                                end
                                tmpinfo = niftiinfo([d(i).folder s d(i).name]);
                                v = niftiread(tmpinfo);
                                app.inBrainInfo = tmpinfo;
                                if i == 1
                                    sz = size(v);
                                    app.inBrain(i,:) = v(:);
                                else
                                    if sz == size(v)
                                        app.inBrain(i,:) = v(:);
                                    else
                                        uialert(app.gBATUIFigure,'Some of the nifti files you have pointed to have different sizes. Please fix before continuing.','Data mismatch');
                                    end
                                end
                            end
                            delete(p)
                        end
                    else
                        [~,~,ext] = fileparts(f);
                        if strcmpi(ext,'.mat')
                            app.inBrainPth = f;
                            tmp = load(app.inBrainPth);
                            list = fieldnames(tmp);
                            if length(list) > 1
                                [idx,tf] = listdlg('ListString',list,'PromptString',{'Select variable from your mat file', 'that represent your brain','data'},'SelectionMode','single');
                                if tf
                                    app.inBrain = tmp.(list{idx(1)});
                                end

                                [idx,tf] = listdlg('ListString',list,'PromptString',{'Now select variable from your mat file', 'that represent your sub','IDs'},'SelectionMode','single');
                                if tf
                                    app.inBrainSubs = tmp.(list{idx(1)});
                                end
                            else
                                uialert(app.gBATUIFigure,'If loading brain data as mat file you must have separate subject name vector and brain matrix (i.e., at least 2 variables in the file)','Data missing');
                            end
                        else
                            app.BraindatafileorfolderEditField.Value = '';
                            uialert(app.gBATUIFigure,'Error parsing your path...please see instructions.','Path error');
                        end
                    end
                end

                % check if there is a mask loaded in first...if so, apply it
                if ~isempty(app.brainMask)
                    if length(app.brainMask) > size(app.inBrain,2)
                        uialert(app.gBATUIFigure,'Your already loaded in brain mask has a dimension mismatch. Removing the mask...','Data mismatch');
                        app.brainMask = [];
                        app.LoadamaskButton.Value = false;
                        if ~isempty(app.inROI)
                            uialert(app.gBATUIFigure,'You had an atlas loaded that worked with the mask but we assume the subject-level data is correct so we have removed the atlas as well','Data mismatch');
                            app.inROI = [];
                            app.inROIPath = [];
                            app.AtlasniftifileoptionalEditField.Value = '';
                        end
                        % else
                        %     app.inBrain = app.inBrain(mask,:);
                    end
                end

                % if an ROI has already been loaded in, let's convert...
                if ~isempty(app.inROI)
                    % first, check ROI/input data availability
                    if isequal(length(app.inROI),size(app.inBrain,2))


                    else
                        uialert(app.gBATUIFigure,'Your ROI and subject-level brain data have a dimension mismatch. Erasing ROI as we assume the subject-level data is correct','Data mismatch');
                        app.inROI = [];
                        app.inROIPath = [];
                        app.AtlasniftifileoptionalEditField.Value = '';
                    end
                end
            else
                app.inBrain = [];
                app.inBrainInfo = [];
                app.inBehSubs = [];
                app.inBrainSubs = [];
                app.inBrainPth = [];
                app.inBrain2 = [];
                app.inBrainPth2 = [];
                app.inBrainSubs2 = [];
                app.inROIMetaData = [];
                app.SecondBraindatafileorfolderEditField.Visible = 'off';
                app.SecondBraindatafileorfolderEditFieldLabel.Visible = 'off';
                app.LoadButton_4.Visible = 'off';
                app.DropDown.Visible = 'off';
                app.LoadROItissuedefinitionsButton.Visible = 'off';
                app.SecondBraindatafileorfolderEditField.Value = '';
                app.LoadROItissuedefinitionsButton.Value = false;

                %app.BraindatafileorfolderEditField.Value = '';
            end
            toolboxUpdateOptions(app)
        end

        % Value changed function: BehavioraldatafileEditField_2
        function changeOutPath(app, event)
            f = app.BehavioraldatafileEditField_2.Value;
            if ~isempty(f)
                if ~exist(app.BehavioraldatafileEditField_2.Value,'dir')
                    try
                        mkdir(app.BehavioraldatafileEditField_2.Value)
                    catch
                        uialert(app.gBATUIFigure,'Could not create the requested folder for outputting files (check permissions maybe)','Folder creation error');
                    end
                    app.oPth = app.BehavioraldatafileEditField_2.Value;
                else
                    app.oPth = app.BehavioraldatafileEditField_2.Value;
                    uialert(app.gBATUIFigure,'Folder already exists. Caution, we overwrite files!! If this is a problem select a different folder.','Warn about overwritting');
                end
            else
                app.oPth = [];
            end
            toolboxUpdateOptions(app)
        end

        % Value changed function: AtlasniftifileoptionalEditField
        function changeAtlasPath(app, event)
            f = app.AtlasniftifileoptionalEditField.Value;
            if ~isempty(f)
                [~,~,ext] = fileparts(f);
                if strcmpi(ext,'.nii') || strcmpi(ext,'.gz')
                    tmpinfo = niftiinfo(f);
                    app.inROIInfo = tmpinfo;
                    v = niftiread(tmpinfo);
                %elseif strcmpi(ext,'.mat')
                    % tmp = load(f);
                    % list = fieldnames(tmp);
                    % if length(list) > 1
                    %     [idx,tf] = listdlg('ListString',list,'PromptString',{'Select variable from your mat file', 'that represent atlas','data'},'SelectionMode','single');
                    %     if tf
                    %         v = tmp.(list{idx(1)});
                    %     end
                    % else
                    %     v = tmp.(list{1});
                    % end
                else
                    uialert(app.gBATUIFigure,'Error parsing your path...please see instructions (did you provide a nifti file?).','Path error');
                end

                % if brain is not empty, check that roi is same size...
                if ~isempty(app.inBrain)
                    if ~isequal(size(app.inBrain,2),length(v(:)))
                        uialert(app.gBATUIFigure,'The atlas nifti is a different dimensionality than the brain data you have already loaded in. Ignoring your atlas...','Data mismatch');
                        app.inROIPath = [];
                        app.AtlasniftifileoptionalEditField.Value  = '';
                    else
                        app.inROI = v(:);
                    end
                else
                    app.inROI = v(:);
                end

                % if roi and brain are same size or only roi exists check
                % against mask. Remove mask if does not fit roi
                if ~isempty(app.brainMask) && ~isempty(app.inROI)
                    if length(app.brainMask) > length(app.inROI)
                        uialert(app.gBATUIFigure,'Your already loaded in brain mask has a dimension mismatch. Removing the mask...','Data mismatch');
                        app.brainMask = [];
                        app.LoadamaskButton.Value = false;
                        % else
                        %     app.inROI = app.inROI(mask);
                    end
                end
            else
                app.inROIPath = [];
                app.inROI = [];
            end
            toolboxUpdateOptions(app)
        end

        % Selection changed function: AnalysistorunButtonGroup
        function analysisSelection(app, event)
            % only when analysis type changes, reset number of permutations
            % and correction to default...
            app.perms = app.defaults.nPerms;
            app.boots = app.defaults.nBoot;
            buttons = app.OptionsforpvaluesandcorrectionsButtonGroup.Buttons;
            buttonNames = arrayfun(@(btn) btn.Text, buttons, 'UniformOutput', false);
            id = find(ismember(lower(buttonNames),lower(app.defaults.pCorr)));
            if strcmpi(app.OptionsforpvaluesandcorrectionsButtonGroup.Buttons(id).Enable,'on')
                app.OptionsforpvaluesandcorrectionsButtonGroup.Buttons(id).Value = true;
            else
                app.OptionsforpvaluesandcorrectionsButtonGroup.Buttons(1).Value = true;
            end
            toolboxUpdateOptions(app)
        end

        % Selection changed function: RelationshipstoconsiderButtonGroup
        function relationSelection(app, event)
            toolboxUpdateOptions(app)
        end

        % Selection changed function: TailsforanalysisButtonGroup
        function tailSelection(app, event)
            toolboxUpdateOptions(app)
        end

        % Value changed function: EditField
        function addArgs(app, event)
            value = app.EditField.Value;
        end

        % Value changed function: BiascorrectedbootstrappingButton
        function bootSelect(app, event)
            toolboxUpdateOptions(app)
        end

        % Value changed function: UserobustOLSButton
        function robustSelect(app, event)
            toolboxUpdateOptions(app)
        end

        % Selection changed function: 
        % OptionsforpvaluesandcorrectionsButtonGroup
        function multCompSelect(app, event)
            if app.OptionsforpvaluesandcorrectionsButtonGroup.Buttons(2).Value
                answer = inputdlg('How many permutations do you want to perform?','Define permutations',[1 50],{num2str(app.defaults.nPerms)});
                if ~isempty(answer)
                    app.perms = str2double(answer);
                end
            end
            toolboxUpdateOptions(app)
        end

        % Value changed function: 
        % ThresholdforvoxelROIsignificancepEditField
        function voxThreshSelect(app, event)
            toolboxUpdateOptions(app)
        end

        % Value changed function: ThresholdforclustersignificancepEditField
        function clustThreshSelect(app, event)
            toolboxUpdateOptions(app)
        end

        % Value changed function: SavetemporaryfilesButton
        function tempFileSelect(app, event)
            toolboxUpdateOptions(app)
        end

        % Value changed function: CPUparallelizationButton
        function cpuParSelect(app, event)
            toolboxUpdateOptions(app)
        end

        % Value changed function: ZscoredataButton
        function zScoreSelect(app, event)
            toolboxUpdateOptions(app)
        end

        % Callback function
        function removeEmptyROISelect(app, event)
            %toolboxUpdateOptions(app)

        end

        % Value changed function: LoadamaskButton
        function loadMask(app, event)
            if ~app.LoadamaskButton.Value
                app.brainMask = [];
            else
                [f,pth] = uigetfile({'*.nii;*.gz;*.mat'},[pwd],'Please select a file that contains your atlas (.nii, .nii.gz, or a variable from a .mat)');
                if sum(f) ~= 0
                    s = filesep;
                    f = [pth f];
                    [~, ~, ext] = fileparts(f);

                    if strcmpi(ext,'.nii') || strcmpi(ext,'.gz')
                        tmpinfo = niftiinfo(f);
                        v = niftiread(tmpinfo);
                    elseif strcmpi(ext,'.mat')
                        tmp = load(f);
                        list = fieldnames(tmp);
                        if length(list) > 1
                            [idx,tf] = listdlg('ListString',list,'PromptString',{'Select variable from your mat file', 'that represent atlas','data'},'SelectionMode','single');
                            if tf
                                v = tmp.(list{idx(1)});
                            end
                        else
                            v = tmp.(list{1});
                        end
                    end

                    % if brain is not empty check to make sure mask
                    % fits (note, ROI is forced to fit if it is loaded)
                    if ~isempty(app.inBrain) && ~isequal(length(v(:)),size(app.inBrain,2))
                        uialert(app.gBATUIFigure,'The mask nifti is a different dimensionality than the brain data you have already loaded in. Ignoring your mask...','Data mismatch');
                        app.brainMask = [];
                        app.LoadamaskButton.Value = false;
                    else
                        app.brainMask = v;
                    end
                else
                    app.LoadamaskButton.Value = false;
                    app.brainMask = [];
                end
            end
        end

        % Value changed function: ExcludevoxelswithlessthanEditField
        function propSelect(app, event)
            toolboxUpdateOptions(app)

        end

        % Button pushed function: StartButton
        function run(app, event)
            % First, some basic checks...
            brainBackup = app.inBrain;
            brainBackup2 = app.inBrain2;
            behBackup = app.inBeh;
            roiBackup = app.inROI;

            id = find(isnan(app.inBrain));
            app.inBrain(id) = 0;
            id = find(isnan(app.inBrain2));
            app.inBrain2(id) = 0;
            id = find(isnan(app.inROI));
            app.inROI(id) = 0;

            try
                pass = true;
                special = false;
                id1 = find(ismember(app.PredictorListBox.Items,app.PredictorListBox.Value));
                id2 = find(ismember(app.OutcomeListBox.Items,app.OutcomeListBox.Value));
                id3 = find(ismember(app.NuissanceListBox.Items,app.NuissanceListBox.Value));

                if length(id1) == 1 && id1 == 1
                    pass = false;
                    uialert(app.gBATUIFigure,'Your only selected predictor is subjects. Please select something else','Selection error');
                elseif length(id1) > 1 && sum(id1 == 1) > 0
                    id1 = id1(2:end);
                    app.PredictorListBox.Value = app.PredictorListBox.Value(2:end);
                    uialert(app.gBATUIFigure,'You selected Subject as a predictor...assuming this was an error and removing from list of predictors to test','Data missing');
                end

                if length(id2) == 1 && id2 == 1 && strcmpi(app.OutcomeListBox.Enable,'on')
                    pass = false;
                    uialert(app.gBATUIFigure,'Your only outcome variable  is subjects. Please select something else','Selection error');
                elseif length(id2) > 1 && sum(id2 == 1) > 0
                    id2 = id2(2:end);
                    app.OutcomeListBox.Value = app.OutcomeListBox.Value(2:end);
                    uialert(app.gBATUIFigure,'You selected Subject as an outcome variable...assuming this was an error and removing from list of predictors to test','Data missing');
                end

                if length(id3) > 1 && sum(id3 == 1) > 0
                    id3 = id3(2:end);
                    app.NuissanceListBox.Value = app.NuissanceListBox.Value(2:end);
                    uialert(app.gBATUIFigure,'You selected Subject as a nuissance variable...assuming this was an error and removing from list of predictors to test','Data missing');
                end

                % check if brain or behavior or output path is missing
                if isempty(app.inBrain)
                    pass = false;
                    uialert(app.gBATUIFigure,'You have not loaded in any brain data...','Data missing');
                elseif isempty(app.inBeh)
                    pass = false;
                    uialert(app.gBATUIFigure,'You have not loaded in any behavioral data...','Data missing');
                elseif isempty(app.oPth)
                    pass = false;
                    uialert(app.gBATUIFigure,'You have not set up an output directory...','Data missing');
                end

                % if doing two tissue maps, check that ROI definitions exist
                if pass
                    if ~isempty(app.inBrain2)
                        special = true;
                        if ~isempty(app.inROIMetaData)
                            if width(app.inROIMetaData) < 2
                                pass = false;
                                uialert(app.gBATUIFigure,'Your label file is wrong, please see instructions, we need at least two columns');
                            end
                        else
                            pass = false;
                            uialert(app.gBATUIFigure,'You have not loaded in data to determine ROI tissue types','Data missing');
                        end
                    end
                end

                % reorg special data
                if special && pass
                    if strcmpi(app.DropDown.Value,'GM')
                        tmp = app.inBrain;
                        app.inBrain = app.inBrain2;
                        app.inBrain2 = tmp;

                        tmp = app.inBrainSubs;
                        app.inBrainSubs = app.inBrainSubs2;
                        app.inBrainSubs2 = tmp;

                        tmp = app.inBrainPth;
                        app.inBrainPth = app.inBrainPth2;
                        app.inBrainPth2 = tmp;
                        app.DropDown.Value = 'WM';
                    end
                end

                % now match all data
                if pass
                    match = app.inBeh;
                    sid = []; % indices in brain subs that are found in beh
                    idtrck = [];
                    for i = 1:size(app.inBeh,1)
                        id = find(contains(app.inBrainSubs,app.inBeh{i,1},'IgnoreCase',true));
                        if isempty(id)
                            vbm_notes_brain_data{i,1} = ['No corresponding brain data was found. Excluded from analysis.'];
                        elseif length(id) > 1
                            [mv,mi] = min(cellfun(@length, app.inBrainSubs(id)));
                            id = id(mi);
                            idtrck = [idtrck; id];
                            sid = [sid; i];
                            vbm_notes_brain_data{i,1} = ['Multiple matches. Based on shorter string length, chose: ' app.inBrainSubs{id}];
                        else
                            sid = [sid; i];
                            idtrck = [idtrck; id];
                            vbm_notes_brain_data{i,1} = ['Matched to: ' app.inBrainSubs{id}];
                        end
                    end
                    match = addvars(match,vbm_notes_brain_data);

                    if special
                        sid2 = []; % indices in beh data that are found in brain data
                        idtrck2 = [];
                        for i = 1:size(app.inBeh,1)
                            id = find(contains(app.inBrainSubs2,app.inBeh{i,1},'IgnoreCase',true));
                            if isempty(id)
                                vbm_notes_secondary_brain_data{i,1} = ['No corresponding brain data was found. Excluded from analysis.'];
                            elseif length(id) > 1
                                [mv,mi] = min(cellfun(@length, app.inBrainSubs2(id)));
                                id = id(mi);
                                vbm_notes_secondary_brain_data{i,1} = ['Multiple matches. Based on shorter string length, chose: ' app.inBrainSubs2{id}];
                                sid2 = [sid2; i];
                                idtrck2 = [idtrck2; id];
                            else
                                sid2 = [sid2; i];
                                idtrck2 = [idtrck2; id];
                                vbm_notes_secondary_brain_data{i,1} =['Matched to: ' app.inBrainSubs2{id}];
                            end
                        end
                        match = addvars(match,vbm_notes_secondary_brain_data);

                        [c,ia,ib] = intersect(sid,sid2);
                        app.inBeh = app.inBeh(c,:);
                        app.inBrain = app.inBrain(idtrck(ia),:);
                        app.inBrain2 = app.inBrain2(idtrck2(ib),:);
                    else
                        app.inBeh = app.inBeh(sid,:);
                        app.inBrain = app.inBrain(idtrck,:);
                    end
                end
                s = filesep;
                if pass
                    writetable(match,[app.oPth s 'input_data_with_matching_notes.csv'])
                end

                selection = uiconfirm(app.gBATUIFigure, ...
                    ["Please check that your nifti images and behavioral data were matched appropriately (see: " [app.oPth s 'input_data_with_matching_notes.csv)']],...
                    "Is the file matching acceptable to proceed?", ...
                    "Options",["Looks good","Exit analysis"], ...
                    "DefaultOption",2,"CancelOption",2,"Icon","warning");
                if strcmpi(selection,'exit analysis')
                    pass = false;
                end

                % now we apply all of our masks to all of the data
                if pass
                    % need to backup atlas file as we will be masking portions of
                    % it out of the analyis but for visualization it makes sense to
                    % show each ROI as is (e.g., not the entire ROI will overlap
                    % with GM/WM)
                    roiBackup = app.inROI;

                    % now starting with the manual mask...
                     if ~isempty(app.brainMask)
                         mask2 = find(app.brainMask ~= 0);
                    %     app.inBrain = app.inBrain(:,mask);
                    % 
                    %     if ~isempty(app.inROI)
                    %         app.inROI = app.inROI(mask);
                    %     end
                    % 
                    %     if special
                    %         app.inBrain2 = app.inBrain2(:,mask);
                    %     end
                     else
                         mask2 = 1:size(app.inBrain,2);
                     end
                    % else
                        mask = 1:size(app.inBrain,2);
                    % end

                    % start application of the mean value threshold...
                    p = uiprogressdlg(app.gBATUIFigure,'Title','Applying mask(s)...',...
                        'Message','Starting up...','Cancelable','off');

                    s1 = sum(app.inBrain,[],"omitnan")./size(app.inBrain,1); % masked image of mean map
                    pmid = find(s1 <= app.ExcludevoxelswithlessthanEditField_2.Value);
                    k1 = find(s1 > app.ExcludevoxelswithlessthanEditField_2.Value);
                    if length(mask) ~= length(mask2)
                        k1 = intersect(k1,mask2);
                    end
                    % but first, save mean map...
                    tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                    tmp(mask) = s1;
                    id = find(isnan(tmp)); tmp(id) = 0;
                    if ~special
                        niftiwrite(single(tmp),[app.oPth s 'meanSubMap'],app.inBrainInfo)
                        gzip([app.oPth s 'meanSubMap.nii'])
                        delete([app.oPth s 'meanSubMap.nii'])
                    else
                        niftiwrite(single(tmp),[app.oPth s 'meanSubMap_GM'],app.inBrainInfo)
                        gzip([app.oPth s 'meanSubMap_GM.nii'])
                        delete([app.oPth s 'meanSubMap_GM.nii'])
                        s2 = sum(app.inBrain2,[],"omitnan")./size(app.inBrain2,1); % masked image of mean map
                        k2 = find(s2 > app.ExcludevoxelswithlessthanEditField_2.Value);
                        if length(mask) ~= length(mask2)
                            k2 = intersect(k2,mask2);
                        end
                        tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                        tmp(mask) = s2;
                        pmid2 = find(s2 <= app.ExcludevoxelswithlessthanEditField_2.Value); % less than or equal to will be made NaN
                        id = find(isnan(tmp)); tmp(id) = 0;
                        niftiwrite(single(tmp),[app.oPth s 'meanSubMap_WM'],app.inBrainInfo)
                        gzip([app.oPth s 'meanSubMap_WM.nii'])
                        delete([app.oPth s 'meanSubMap_WM.nii'])
                    end
                    p.Value = 0.1;
                    p.Message = ['Working on mean morphometry map threhsold(s)'];

                    % after saving mean maps, apply the threshold by making
                    % everything below it NaN
                    % app.inBrain(:,pmid) = NaN; % mask indices apply, but this does not...
                    % %app.inBrain(:,pmid) = [];
                    % if special
                    %     app.inBrain2(:,pmid2) = NaN;
                    %     %app.inBrain2(:,pmid) = [];
                    % end

                    % now save out these maps as well...
                    tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                    tmp(mask(k1)) = 1;
                    %tmp(omask) = 0;
                    id = find(isnan(tmp)); tmp(id) = 0; % we will not look at nan voxels anyways...
                    if ~special
                        niftiwrite(single(tmp),[app.oPth s 'meanSubMap_thresholded_mask'],app.inBrainInfo)
                        gzip([app.oPth s 'meanSubMap_thresholded_mask.nii'])
                        delete([app.oPth s 'meanSubMap_thresholded_mask.nii'])
                        try
                            if app.MakebrainfigsButton.Value
                                [tmp1,tmp2,~,~] = brainMontager([app.oPth s 'meanSubMap_thresholded_mask.nii.gz'],[app.oPth s 'meanSubMap.nii.gz'],round(linspace(1,app.inBrainInfo.raw.dim(4),20)),5,[],'cool',[],[1 1 1],false,1000,[],'gray',[],0.3,'ud',true,false,false);
                                saveas(tmp1{1}.Parent.Parent.Parent,[app.oPth s 'meanSubMap.png'])
                                close(tmp1{1}.Parent.Parent.Parent)
                            end
                        catch
                        end
                    else
                        niftiwrite(single(tmp),[app.oPth s 'meanSubMap_GM_thresholded_mask'],app.inBrainInfo)
                        gzip([app.oPth s 'meanSubMap_GM_thresholded_mask.nii'])
                        delete([app.oPth s 'meanSubMap_GM_thresholded_mask.nii'])
                        tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                        tmp(mask(k2)) = 1;
                        %tmp(omask) = 0;
                        id = find(isnan(tmp)); tmp(id) = 0;
                        niftiwrite(single(tmp),[app.oPth s 'meanSubMap_WM_thresholded_mask'],app.inBrainInfo)
                        gzip([app.oPth s 'meanSubMap_WM_thresholded_mask.nii'])
                        delete([app.oPth s 'meanSubMap_WM_thresholded_mask.nii'])
                        try
                            if app.MakebrainfigsButton.Value
                                [tmp1,tmp2,~,~] = brainMontager([app.oPth s 'meanSubMap_WM_thresholded_mask.nii.gz'],[app.oPth s 'meanSubMap_WM.nii.gz'],round(linspace(1,app.inBrainInfo.raw.dim(4),20)),5,[],'cool',[],[1 1 1],false,1000,[],'gray',[],0.3,'ud',true,false,false);
                                saveas(tmp1{1}.Parent.Parent.Parent,[app.oPth s 'meanSubMap_WM.png'])
                                close(tmp1{1}.Parent.Parent.Parent)
                                [tmp1,tmp2,~,~] = brainMontager([app.oPth s 'meanSubMap_GM_thresholded_mask.nii.gz'],[app.oPth s 'meanSubMap_GM.nii.gz'],round(linspace(1,app.inBrainInfo.raw.dim(4),20)),5,[],'cool',[],[1 1 1],false,1000,[],'gray',[],0.3,'ud',true,false,false);
                                saveas(tmp1{1}.Parent.Parent.Parent,[app.oPth s 'meanSubMap_GM.png'])
                                close(tmp1{1}.Parent.Parent.Parent)
                            end
                        catch
                        end
                    end
                    p.Value = 0.2;
                    p.Message = ['Working on subject overlap map(s)'];

                    % now get subject overlap map and save...
                    %o = sum(~isnan(app.inBrain(:,mask)), 1)./size(app.inBrain(:,mask),1); % proportion of subjects with values other than NaN in each voxel
                    o = sum(app.inBrain ~= 0)./size(app.inBrain,1);
                    mid = find(o < app.ExcludevoxelswithlessthanEditField.Value);
                    k3 = find(o >= app.ExcludevoxelswithlessthanEditField.Value);
                    tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                    tmp(mask) = o;
                    id = find(isnan(tmp)); tmp(id) = 0;
                    if ~special
                        niftiwrite(single(tmp),[app.oPth s 'overlapSubMap'],app.inBrainInfo)
                        gzip([app.oPth s 'overlapSubMap.nii'])
                        delete([app.oPth s 'overlapSubMap.nii'])
                    else
                        niftiwrite(single(tmp),[app.oPth s 'overlapSubMap_GM'],app.inBrainInfo)
                        gzip([app.oPth s 'overlapSubMap_GM.nii'])
                        delete([app.oPth s 'overlapSubMap_GM.nii'])
                        %o2 = sum(~isnan(app.inBrain2(:,mask)), 1)./size(app.inBrain2(:,mask),1); % proportion of subjects with values other than NaN in each voxel
                        o2 = sum(app.inBrain2 ~= 0)./size(app.inBrain2,1);
                        mid2 = find(o2 < app.ExcludevoxelswithlessthanEditField.Value);
                        k4 = find(o2 >= app.ExcludevoxelswithlessthanEditField.Value);
                        tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                        tmp(mask) = o2;
                        id = find(isnan(tmp)); tmp(id) = 0;
                        niftiwrite(single(tmp),[app.oPth s 'overlapSubMap_WM'],app.inBrainInfo)
                        gzip([app.oPth s 'overlapSubMap_WM.nii'])
                        delete([app.oPth s 'overlapSubMap_WM.nii'])
                    end
                    p.Value = 0.3;

                    % after saving OVERLAP maps, apply the threshold by making
                    % everything below it NaN
                    % app.inBrain(:,mid) = NaN; % mask indices apply, but this does not...
                    % if special
                    %     app.inBrain2(:,mid2) = NaN;
                    % end
                    % now save out these maps as well...
                    tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                    tmp(mask(k3)) = 1;
                    %tmp(omask) = 0;
                    id = find(isnan(tmp)); tmp(id) = 0; % we will not look at nan voxels anyways...
                    if ~special
                        niftiwrite(single(tmp),[app.oPth s 'overlapSubMap_thresholded_mask'],app.inBrainInfo)
                        gzip([app.oPth s 'overlapSubMap_thresholded_mask.nii'])
                        delete([app.oPth s 'overlapSubMap_thresholded_mask.nii'])
                        try
                            if app.MakebrainfigsButton.Value
                                [tmp1,tmp2,~,~] = brainMontager({[app.oPth s 'overlapSubMap.nii.gz'],[app.oPth s 'overlapSubMap_thresholded_mask.nii.gz']},[app.oPth s 'meanSubMap.nii.gz'],round(linspace(1,app.inBrainInfo.raw.dim(4),20)),5,[],'cool',[],[1 1 1],false,1000,[],'gray',[],0.3,'ud',true,false,false);
                                saveas(tmp1{1}.Parent.Parent.Parent,[app.oPth s 'overlapSubMap.png'])
                                close(tmp1{1}.Parent.Parent.Parent)
                            end
                        catch
                        end
                    else
                        niftiwrite(single(tmp),[app.oPth s 'overlapSubMap_GM_thresholded_mask'],app.inBrainInfo)
                        gzip([app.oPth s 'overlapSubMap_GM_thresholded_mask.nii'])
                        delete([app.oPth s 'overlapSubMap_GM_thresholded_mask.nii'])
                        tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                        tmp(mask(k4)) = 1;
                        %tmp(omask) = 0;
                        id = find(isnan(tmp)); tmp(id) = 0;
                        niftiwrite(single(tmp),[app.oPth s 'overlapSubMap_WM_thresholded_mask'],app.inBrainInfo)
                        gzip([app.oPth s 'overlapSubMap_WM_thresholded_mask.nii'])
                        delete([app.oPth s 'overlapSubMap_WM_thresholded_mask.nii'])
                        try
                            if app.MakebrainfigsButton.Value
                                [tmp1,tmp2,~,~] = brainMontager({[app.oPth s 'overlapSubMap_WM.nii.gz'],[app.oPth s 'overlapSubMap_WM_thresholded_mask.nii.gz']},[app.oPth s 'meanSubMap_WM.nii.gz'],round(linspace(1,app.inBrainInfo.raw.dim(4),20)),5,[],'cool',[],[1 1 1],false,1000,[],'gray',[],0.3,'ud',true,false,false);
                                saveas(tmp1{1}.Parent.Parent.Parent,[app.oPth s 'overlapSubMap_WM.png'])
                                close(tmp1{1}.Parent.Parent.Parent)
                                [tmp1,tmp2,~,~] = brainMontager({[app.oPth s 'overlapSubMap_GM.nii.gz'],[app.oPth s 'overlapSubMap_GM_thresholded_mask.nii.gz']},[app.oPth s 'meanSubMap_GM.nii.gz'],round(linspace(1,app.inBrainInfo.raw.dim(4),20)),5,[],'cool',[],[1 1 1],false,1000,[],'gray',[],0.3,'ud',true,false,false);
                                saveas(tmp1{1}.Parent.Parent.Parent,[app.oPth s 'overlapSubMap_GM.png'])
                                close(tmp1{1}.Parent.Parent.Parent)
                            end
                        catch
                        end
                    end
                    if all(isnan(app.inBrain(:)))
                        pass = false;
                        uialert(app.gBATUIFigure,'Check your masks and thresholds in the output directory, no voxels were defined for analysis...','Data missing');
                    end
                    p.Value = 0.4;

                    % convert to ROIs if needed...
                    k1 = intersect(k1,k3);
                    if special
                        k2 = intersect(k2,k4);
                    end
                    if isempty(app.inROI)
                        morph = app.inBrain(:,k1); % WHAT TO DO WITH k1 + k2
                        % remove everything outside of k1 and k2...
                        p.Value = 1;
                        txt = ["Please check information about masks and confirm everything looks correct (see png files in: " [app.oPth s '']];
                    else
                        p.Message = ['Binning voxel data by ROI...this can take time as we also generate lots of intermediary images...'];
                        % extract ROIs. But first make some ROI images...
                        % create an ROI map...
                        try
                            if app.MakebrainfigsButton.Value
                                if special
                                    [tmp1,tmp2,~,~] = brainMontager(app.inROIPath,[app.oPth s 'meanSubMap_GM.nii.gz'],round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'jet',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true);
                                else
                                    [tmp1,tmp2,~,~] = brainMontager(app.inROIPath,[app.oPth s 'meanSubMap.nii.gz'],round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'jet',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true);
                                end
                                saveas(tmp1{1}.Parent.Parent.Parent,[app.oPth s 'meanSubROIMap.png'])
                                close(tmp1{1}.Parent.Parent.Parent)
                            end
                        catch
                        end

                        p.Value = 0.5;
                        % create an ROI map with removed values...
                        tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                        tmp(mask(k1)) = app.inROI(k1);
                        %tmp(mask(mid)) = 0;
                        id = find(isnan(tmp)); tmp(id) = 0; % we will not look at nan voxels anyways...
                        if ~special
                            niftiwrite(single(tmp),[app.oPth s 'meanSubROIMapWithMasks'],app.inBrainInfo)
                            gzip([app.oPth s 'meanSubROIMapWithMasks.nii'])
                            delete([app.oPth s 'meanSubROIMapWithMasks.nii'])
                            try
                                if app.MakebrainfigsButton.Value
                                    [tmp1,tmp2,~,~] = brainMontager([app.oPth s 'meanSubROIMapWithMasks.nii.gz'],[app.oPth s 'meanSubMap.nii.gz'],round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'jet',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true);
                                    saveas(tmp1{1}.Parent.Parent.Parent,[app.oPth s 'meanSubROIMapWithMasks.png'])
                                    close(tmp1{1}.Parent.Parent.Parent)
                                end
                            catch
                            end
                        else
                            niftiwrite(single(tmp),[app.oPth s 'meanSubROIMapWithMasks_GM'],app.inBrainInfo)
                            gzip([app.oPth s 'meanSubROIMapWithMasks_GM.nii'])
                            delete([app.oPth s 'meanSubROIMapWithMasks_GM.nii'])

                            tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                            tmp(mask(k2)) = app.inROI(k2);
                            %tmp(mask(mid2)) = 0;
                            id = find(isnan(tmp)); tmp(id) = 0; % we will not look at nan voxels anyways...
                            niftiwrite(single(tmp),[app.oPth s 'meanSubROIMapWithMasks_WM'],app.inBrainInfo)
                            gzip([app.oPth s 'meanSubROIMapWithMasks_WM.nii'])
                            delete([app.oPth s 'meanSubROIMapWithMasks_WM.nii'])
                            try
                                if app.MakebrainfigsButton.Value
                                    [tmp1,tmp2,~,~] = brainMontager([app.oPth s 'meanSubROIMapWithMasks_GM.nii.gz'],[app.oPth s 'meanSubMap_GM.nii.gz'],round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'jet',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true);
                                    saveas(tmp1{1}.Parent.Parent.Parent,[app.oPth s 'meanSubROIMapWithMasks_GM.png'])
                                    close(tmp1{1}.Parent.Parent.Parent)
                                    [tmp1,tmp2,~,~] = brainMontager([app.oPth s 'meanSubROIMapWithMasks_WM.nii.gz'],[app.oPth s 'meanSubMap_WM.nii.gz'],round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'jet',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true);
                                    saveas(tmp1{1}.Parent.Parent.Parent,[app.oPth s 'meanSubROIMapWithMasks_WM.png'])
                                    close(tmp1{1}.Parent.Parent.Parent)
                                end
                            catch
                            end
                        end
                        p.Value = 0.8;

                        % now extract ROI ids so we don't have to
                        % redo this later...
                        un = unique(app.inROI);
                        id = find(un == 0);
                        un(id) = [];
                        for i = 1:length(un)
                            rois{i} = find(app.inROI == un(i));
                        end

                        % if not special just take average
                        if ~special
                            for i = 1:length(rois) % loop over ROIs
                                [~,~,ib] = intersect(rois{i},k1);
                                if ~isempty(rois{i})
                                    morph(:,i) = mean(app.inBrain(:,ib),2,'omitnan');
                                    count(i,1) = mean(sum(~isnan(app.inBrain(:,ib)), 2)./length(ib),'omitnan');
                                else
                                    morph(:,i) = NaN;
                                    count(i,1) = 0;
                                end
                            end
                        else
                            unMeta = table2array(app.inROIMetaData(:,1));
                            tType = table2array(app.inROIMetaData(:,end));
                            for i = 1:length(rois) % loop over ROIs
                                id = find(unMeta == un(i));
                                if ~isempty(id)
                                    [~,~,ib] = intersect(rois{i},k1);
                                    [~,~,ib2] = intersect(rois{i},k2);
                                    % ib = rois{i};
                                    % ib2 = ib;
                                    if tType(id) == 1
                                        morph(:,i) = mean(app.inBrain(:,ib),2,'omitnan');
                                        count(i,1) = mean(sum(~isnan(app.inBrain(:,ib)), 2)./length(ib),'omitnan');
                                    elseif tType(id) == 2
                                        morph(:,i) = mean(app.inBrain2(:,rois{i}),2,'omitnan');
                                        count(i,1) = mean(sum(~isnan(app.inBrain(:,ib2)), 2)./length(ib2),'omitnan');
                                    else
                                        morph(:,i) = NaN;
                                        count(i,1) = 0;
                                    end
                                else
                                    morph(:,i) = NaN;
                                    count(i,1) = 0;
                                end
                            end
                        end

                        if all(isnan(morph))
                            pass = false;
                            uialert(app.gBATUIFigure,'Check your masks and thresholds, no ROIs were defined for analysis...','Data missing');
                        else
                            % apply threshold...
                            roiData = array2table(un);
                            roiData.Properties.VariableNames(end) = {'ROI index'};
                            if special
                                [~,iatmp,ibtmp] = intersect(app.inROIMetaData.Var1,roiData.("ROI index"));
                                for jj = 2:size(app.inROIMetaData,2)
                                    if jj ~= size(app.inROIMetaData,2)
                                        roiData(:,jj) = {'NaN'};
                                        roiData.Properties.VariableNames{jj} = app.inROIMetaData.Properties.VariableNames{jj};
                                    else
                                        roiData.(app.inROIMetaData.Properties.VariableNames{jj}) = NaN(height(roiData), 1);
                                    end
                                    roiData(ibtmp,jj) = app.inROIMetaData(iatmp,jj);
                                end
                            end

                            %ro = sum(~isnan(morph), 1)./size(morph,1); % proportion of subjects with values other than NaN in each roi
                            ro = sum(morph > 0,1)./size(morph,1);
                            an = repmat({'Not analyzed (no subject data)'},height(un),1);
                            an(find(count > 0)) = {'Analyzed'};
                            mn = mean(morph,1,'omitnan')';
                            id = find(ro <= app.ExcludevoxelswithlessthanEditField.Value);
                            an(id) = {['Fewer or equal to ' num2str(app.ExcludevoxelswithlessthanEditField.Value*100) '% of subjects had any morphometry values inside this ROI']};
                            roiData = addvars(roiData,mn);
                            roiData.Properties.VariableNames{end} = ['Mean morphometry across subjects for ROI'];
                            roiData = addvars(roiData,ro');
                            roiData.Properties.VariableNames{end} = ['Proportion of subs with any morphometry values > 0 in ROI'];
                            roiData = addvars(roiData,count);
                            roiData.Properties.VariableNames{end} = ['Average proportion of ROI that subjects had data in'];
                            roiData = addvars(roiData,an);
                            roiData.Properties.VariableNames{end} = ['Excluded subject notes'];
                            writetable(roiData,[app.oPth s 'ROI_information.csv'])
                        end
                        p.Value = 1;
                        txt = ["Please check information about masks and ROIs before proceeding (see: " [app.oPth s 'ROI_information.csv as well as .pngs in this folder)']];
                    end

                    if pass
                        selection = uiconfirm(app.gBATUIFigure, ...
                            txt,...
                            "Are the masks and/or ROI binning acceptable?", ...
                            "Options",["Looks good","Exit analysis"], ...
                            "DefaultOption",2,"CancelOption",2,"Icon","warning");
                        if strcmpi(selection,'exit analysis')
                            pass = false;
                        end
                    end

                    % now pass all of the information on to vbm.m
                    morphb = morph;
                    roiDataBackup = roiData;
                    if pass
                        % loop over behavior and gather args...
                        for i = 1:length(id1)
                            x = app.inBeh.(app.PredictorListBox.Items{id1});
                            if strcmpi(app.OutcomeListBox.Enable,'on') & isempty(intersect(id2,1))
                                y = app.inBeh.(app.OutcomeListBox.Value{i});
                            else
                                y = [];
                            end
                            if strcmpi(app.NuissanceListBox.Enable,'on') && (length(id3) > 1  || id3 ~= 1)
                                z = table2array(app.inBeh(:,id3));
                            else
                                z = [];
                            end

                            % for m, must remove cols/features that are all
                            % nan...
                            morph = morphb;
                            roiData = roiDataBackup;
                            nancol = find(sum(isnan(morph), 1) == size(morph,1));
                            morph(:,nancol) = [];
                            if ~isempty(app.inROI)
                                [~,ia,~] = intersect(table2array(roiData(:,1)),nancol);
                                roiData(ia,:) = [];
                            else
                                mask(ia) = []; % we removed these values so now they are part of our complete exclusion mask
                            end

                            if isempty(app.EditField.Value)
                                args = {};
                            else
                                args = strsplit(app.EditField.Value, ',');
                            end

                            if app.MediationButton.Value
                                ant = 'mediation';
                                if app.BiascorrectedbootstrappingButton.Value
                                    args = [args {'boot'}];
                                end
                                if app.UserobustOLSButton.Value
                                    args = [args {'robust'}];
                                end
                            elseif app.PartialcorrelationButton.Value && app.LinearButton.Value
                                ant = 'partial';
                            elseif app.CorrelationButton.Value && app.LinearButton.Value
                                ant = 'pearson';
                            elseif app.PartialcorrelationButton.Value && app.LinearandNonlinearButton.Value
                                ant = 'partialDistance';
                            elseif app.CorrelationButton.Value && app.LinearandNonlinearButton.Value
                                ant = 'distance';
                            end

                            if app.FDRButton.Value|| app.NocorrectionButton.Value
                                perms = 0;
                            else
                                perms = app.perms;
                            end

                            if ~app.BootstrappvaluesButton_2.Value
                                boots = 0;
                            else
                                boots = app.boots;
                            end

                            if app.NocorrectionButton.Value|| app.CorrectionbypermutedclustersizeButton.Value
                                p = 'default';
                            elseif app.FDRButton.Value
                                p = 'fdr';
                            end

                            if isempty(app.ThresholdforvoxelROIsignificancepEditField.Value)
                                vthr = str2double(app.ThresholdforvoxelROIsignificancepEditField.Placeholder);
                            else
                                vthr = app.ThresholdforvoxelROIsignificancepEditField.Value;
                            end
                            if isempty(app.ThresholdforclustersignificancepEditField.Value)
                                cthr = str2double(app.ThresholdforclustersignificancepEditField.Placeholder);
                            else
                                cthr = app.ThresholdforclustersignificancepEditField.Value;
                            end

                            if ~isempty(app.inROI)
                                sz = [];
                            else
                                sz = [app.inBrainInfo.raw.dim(2:4)];
                            end
                            if app.BothButton_2.Value
                                t = 'both';
                            elseif app.NegativeButton.Value
                                t = 'neg';
                            elseif app.PositiveButton.Value
                                t = 'pos';
                            end

                            prnm = {'a','b','c''','c','ab','tOrz'};

                            [oSv,oSm,oR,oRm,oP,oPc,oPm,oPcm,oPth,oPall,oPthStd,oPthm,oPallm,oPthStdm,ccIds,pcSurvPos,pcSurvNeg,cclMxPPos,cclMxPNeg,oT] = gbat_start(x,y,morph,z,ant,perms,p,vthr,cthr,sz,mask,app.SavetemporaryfilesButton.Value,app.ZscoredataButton.Value,[args],app.CPUparallelizationButton.Value',t,app.BootstrappvaluesButton_2.Value,boots);

                            % reshape if necessary for oR and oP....
                            sztmp = size(oR);
                            if sztmp(1) == 1
                                oR = oR';
                                oP = oP';
                            end

                            % now write outputs
                            if ~isempty(app.inROI)
                                un(nancol) = [];
                                tmp = array2table(un);
                                tmp.Properties.VariableNames{end} = 'ROI index';
                                if ~strcmpi(ant,'mediation')
                                    tmp = addvars(tmp,oR);
                                    tmp.Properties.VariableNames{end} = 'stat (raw)';
                                    try
                                        tmp = addvars(tmp,oP);
                                    catch
                                        tmp = addvars(tmp,oP);
                                    end
                                    tmp.Properties.VariableNames{end} = 'p-value (uncorr)';
                                else
                                    tmp = addvars(tmp,oR);
                                    % if special
                                    %     [ca,ia,ib] = intersect(un,table2array(app.inROIMetaData(:,1)));
                                    %     nms = app.inROIMetaData(ib,1);
                                    % end

                                    tmp.Properties.VariableNames{end} = ['indirect path coeff (ab)' ];
                                    tmp = addvars(tmp,oPth(:,1));
                                    tmp.Properties.VariableNames{end} = ['path ' prnm{1} ' coeff'];
                                    tmp = addvars(tmp,oPth(:,2));
                                    tmp.Properties.VariableNames{end} = ['path ' prnm{2} ' coeff'];
                                    tmp = addvars(tmp,oPth(:,3));
                                    tmp.Properties.VariableNames{end} = ['path ' prnm{3} ' coeff'];
                                    tmp = addvars(tmp,oPth(:,4));
                                    tmp.Properties.VariableNames{end} = ['path ' prnm{4} '  coeff'];
                                    tmp = addvars(tmp,oT);
                                    tmp.Properties.VariableNames{end} = ['path ' prnm{6}];

                                    tmp = addvars(tmp,oPall(:,1));
                                    tmp.Properties.VariableNames{end} = ['path ' prnm{1} ' p-value (not bootstrapped if no bias corr)'];
                                    tmp = addvars(tmp,oPall(:,2));
                                    tmp.Properties.VariableNames{end} = ['path ' prnm{2} ' p-value (not bootstrapped if no bias corr)'];
                                    tmp = addvars(tmp,oPall(:,3));
                                    tmp.Properties.VariableNames{end} = ['path ' prnm{3} ' p-value (not bootstrapped if no bias corr)'];
                                    tmp = addvars(tmp,oPall(:,4));
                                    tmp.Properties.VariableNames{end} = ['path ' prnm{4} ' p-value (not bootstrapped if no bias corr)'];
                                    tmp = addvars(tmp,oPall(:,5));
                                    tmp.Properties.VariableNames{end} = ['indirect path p-value (ab)' ];

                                    tmp = addvars(tmp,oPthStd(:,1));
                                    tmp.Properties.VariableNames{end} = ['path ' prnm{1} ' std coeff'];
                                    tmp = addvars(tmp,oPthStd(:,2));
                                    tmp.Properties.VariableNames{end} = ['path ' prnm{2} ' std coeff'];
                                    tmp = addvars(tmp,oPthStd(:,3));
                                    tmp.Properties.VariableNames{end} = ['path ' prnm{3} ' std coeff'];
                                    tmp = addvars(tmp,oPthStd(:,4));
                                    tmp.Properties.VariableNames{end} = ['path ' prnm{4} ' std coeff'];
                                    tmp = addvars(tmp,oPthStd(:,5));
                                    tmp.Properties.VariableNames{end} = ['path ' prnm{5} ' std coeff'];
                                end
                                writetable(tmp,[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_analysis_ROI_results.csv'])

                                % write out ROI files...
                                if ~strcmpi(ant,'mediation')
                                    tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                                    for j = 1:length(un)
                                        vid = find(app.inROI == un(j));
                                        tmp(mask(vid)) = oR(j);
                                    end
                                    niftiwrite(single(tmp),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat'],app.inBrainInfo);
                                    gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat.nii']);
                                    delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat.nii']);
                                    if app.MakebrainfigsButton.Value
                                        try
                                            [tmp1,~,~,~,olim] = brainMontagerWithAtlas([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat.nii'],[app.oPth s 'meanSubMap_GM.nii.gz'],app.inROIInfo.Filename,round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'viridis',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true,app.ExcludevoxelswithlessthanEditField_2.Value,'both');
                                            saveas(gcf,[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_' num2str(min(olim)) '_' num2str(max(olim))  '.fig']); close(tmp1{1}.Parent.Parent.Parent)
                                        catch
                                        end
                                    end

                                    tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                                    for j = 1:length(un)
                                        vid = find(app.inROI == un(j));
                                        tmp(mask(vid)) = oP(j);
                                    end
                                    niftiwrite(single(tmp),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_pval'],app.inBrainInfo);
                                    gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_pval.nii']);
                                    delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_pval.nii']);

                                    tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                                    for j = 1:length(un)
                                        vid = find(app.inROI == un(j));
                                        if oP(j) <= vthr
                                            tmp(mask(vid)) = oR(j);
                                        else
                                            tmp(mask(vid)) = 0;
                                        end
                                    end
                                    niftiwrite(single(tmp),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_VoxThresh' num2str(vthr) '.nii'],app.inBrainInfo);
                                    gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_VoxThresh' num2str(vthr) '.nii']);
                                    delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_VoxThresh' num2str(vthr) '.nii']);
                                    if app.MakebrainfigsButton.Value
                                        try
                                            [tmp1,~,~,~,olim] = brainMontagerWithAtlas([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_VoxThresh' num2str(vthr) '.nii.gz'],[app.oPth s 'meanSubMap_GM.nii.gz'],app.inROIInfo.Filename,round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'viridis',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true,app.ExcludevoxelswithlessthanEditField_2.Value,'both');
                                            saveas(gcf,[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_VoxThresh' num2str(vthr) '_' num2str(min(olim)) '_' num2str(max(olim))  '.fig']); close(tmp1{1}.Parent.Parent.Parent)
                                        catch
                                        end
                                    end

                                    if strcmpi(p,'fdr')
                                        ptmp = mafdr(oP,'BHFDR',true);
                                        tmp = ones([app.inBrainInfo.raw.dim(2:4)]);
                                        for j = 1:length(un)
                                            vid = find(app.inROI == un(j));
                                            tmp(mask(vid)) = ptmp(j);
                                        end
                                        niftiwrite(single(tmp),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_pval_FDR'],app.inBrainInfo);
                                        gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_pval_FDR.nii']);
                                        delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_pval_FDR.nii']);

                                        tmp = ones([app.inBrainInfo.raw.dim(2:4)]);
                                        for j = 1:length(un)
                                            vid = find(app.inROI == un(j));
                                            if ptmp(j) <= vthr
                                                tmp(mask(vid)) = oR(j);
                                            else
                                                tmp(mask(vid)) = 0;
                                            end
                                        end
                                        niftiwrite(single(tmp),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_VoxThresh' num2str(vthr) '_corrected.nii'],app.inBrainInfo);
                                        gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_VoxThresh' num2str(vthr) '_corrected.nii']);
                                        delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_VoxThresh' num2str(vthr) '_corrected.nii']);
                                        if app.MakebrainfigsButton.Value
                                            try
                                                [tmp1,~,~,~,olim] = brainMontagerWithAtlas([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_VoxThresh' num2str(vthr) '_corrected.nii.gz'],[app.oPth s 'meanSubMap_GM.nii.gz'],app.inROIInfo.Filename,round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'viridis',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true,app.ExcludevoxelswithlessthanEditField_2.Value,'both');
                                                saveas(gcf,[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_VoxThresh' num2str(vthr) '_corrected_' num2str(min(olim)) '_' num2str(max(olim))  '.fig']); close(tmp1{1}.Parent.Parent.Parent)
                                            catch
                                            end
                                        end
                                    end
                                else
                                    tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                                    for j = 1:length(un)
                                        vid = find(app.inROI == un(j));
                                        tmp(mask(vid)) = oR(j);
                                    end
                                    niftiwrite(single(tmp),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect_raw'],app.inBrainInfo);
                                    gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect_raw.nii']);
                                    delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect_raw.nii']);

                                    if app.MakebrainfigsButton.Value
                                        try
                                            [tmp1,~,~,~,olim] = brainMontagerWithAtlas([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect_raw.nii.gz'],[app.oPth s 'meanSubMap_GM.nii.gz'],app.inROIInfo.Filename,round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'viridis',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true,app.ExcludevoxelswithlessthanEditField_2.Value,'both');
                                            saveas(gcf,[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect_raw_' num2str(min(olim)) '_' num2str(max(olim))  '.fig']); close(tmp1{1}.Parent.Parent.Parent);
                                        catch
                                        end
                                    end

                                    tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                                    for j = 1:length(un)
                                        vid = find(app.inROI == un(j));
                                        tmp(mask(vid)) = oP(j);
                                    end
                                    niftiwrite(single(tmp),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_pval_medEffect_raw'],app.inBrainInfo);
                                    gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_pval_medEffect_raw.nii']);
                                    delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_pval_medEffect_raw.nii']);

                                    tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                                    for j = 1:length(un)
                                        vid = find(app.inROI == un(j));
                                        tmp(mask(vid)) = oT(j);
                                    end
                                    niftiwrite(single(tmp),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_tOrz_raw'],app.inBrainInfo);
                                    gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_tOrz_raw.nii']);
                                    delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_tOrz_raw.nii']);
                                    if app.MakebrainfigsButton.Value
                                        try
                                            [tmp1,~,~,~,olim] = brainMontagerWithAtlas([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_tOrz_raw.nii.gz'],[app.oPth s 'meanSubMap_GM.nii.gz'],app.inROIInfo.Filename,round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'viridis',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true,app.ExcludevoxelswithlessthanEditField_2.Value,'both');
                                            saveas(gcf,[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_tOrz_raw_' num2str(min(olim)) '_' num2str(max(olim))  '.fig']); close(tmp1{1}.Parent.Parent.Parent);
                                        catch
                                        end
                                    end

                                    tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                                    for j = 1:length(un)
                                        vid = find(app.inROI == un(j));
                                        if oP(j) <= vthr
                                            tmp(mask(vid)) = oR(j);
                                        else
                                            tmp(mask(vid)) = 0;       
                                        end
                                    end
                                    niftiwrite(single(tmp),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect_VoxThresh_' num2str(vthr) '.nii'],app.inBrainInfo);
                                    gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect_VoxThresh_' num2str(vthr) '.nii']);
                                    delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect_VoxThresh_' num2str(vthr) '.nii']);
                                    if app.MakebrainfigsButton.Value
                                        try
                                            [tmp1,~,~,~,olim] = brainMontagerWithAtlas([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect_VoxThresh_' num2str(vthr) '.nii.gz'],[app.oPth s 'meanSubMap_GM.nii.gz'],app.inROIInfo.Filename,round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'viridis',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true,app.ExcludevoxelswithlessthanEditField_2.Value,'both');
                                            saveas(gcf,[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect_VoxThresh_' num2str(vthr) '_' num2str(min(olim)) '_' num2str(max(olim))  '.fig']); close(tmp1{1}.Parent.Parent.Parent);
                                        catch
                                        end
                                    end

                                    tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                                    for j = 1:length(un)
                                        vid = find(app.inROI == un(j));
                                        tmp(mask(vid)) = oPc(j);
                                    end
                                    niftiwrite(single(tmp),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_pval_medEffect_corrected.nii'],app.inBrainInfo);
                                    gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_pval_medEffect_corrected.nii']);
                                    delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_pval_medEffect_corrected.nii']);

                                    % HERE
                                    tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                                    for j = 1:length(un)
                                        vid = find(app.inROI == un(j));
                                        if oPc(j) <= vthr
                                            tmp(mask(vid)) = oR(j);
                                        else
                                            tmp(mask(vid)) = 0;
                                        end
                                    end
                                    niftiwrite(single(tmp),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect_VoxThresh_' num2str(vthr) '_corrected.nii'],app.inBrainInfo);
                                    gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect_VoxThresh_' num2str(vthr) '_corrected.nii']);
                                    delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect_VoxThresh_' num2str(vthr) '_corrected.nii']);
                                    if app.MakebrainfigsButton.Value
                                        try
                                            [tmp1,~,~,~,olim] = brainMontagerWithAtlas([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect_VoxThresh_' num2str(vthr) '_corrected.nii.gz'],[app.oPth s 'meanSubMap_GM.nii.gz'],app.inROIInfo.Filename,round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'viridis',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true,app.ExcludevoxelswithlessthanEditField_2.Value,'both');
                                            saveas(gcf,[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect_VoxThresh_' num2str(vthr) '_corrected_' num2str(min(olim)) '_' num2str(max(olim))  '.fig']); close(tmp1{1}.Parent.Parent.Parent);
                                        catch
                                        end
                                    end

                                    for jj = 1:size(oPall,2)
                                        if jj ~= 5
                                            tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                                            for j = 1:length(un)
                                                vid = find(app.inROI == un(j));
                                                tmp(mask(vid)) = oPall(j,jj);
                                            end
                                            niftiwrite(single(tmp),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_pval'],app.inBrainInfo);
                                            gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_pval.nii']);
                                            delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_pval.nii']);

                                            tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                                            for j = 1:length(un)
                                                vid = find(app.inROI == un(j));
                                                tmp(mask(vid)) = oPthStd(j,jj);
                                            end
                                            niftiwrite(single(tmp),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff_standardized'],app.inBrainInfo);
                                            gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff_standardized.nii']);
                                            delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff_standardized.nii']);
                                            if app.MakebrainfigsButton.Value
                                                try
                                                    [tmp1,~,~,~,olim] = brainMontagerWithAtlas([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff_standardized.nii.gz'],[app.oPth s 'meanSubMap_GM.nii.gz'],app.inROIInfo.Filename,round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'viridis',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true,app.ExcludevoxelswithlessthanEditField_2.Value,'both');
                                                    saveas(gcf,[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff_standardized_' num2str(min(olim)) '_' num2str(max(olim))  '.fig']); close(tmp1{1}.Parent.Parent.Parent);
                                                catch
                                                end
                                            end

                                            tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                                            for j = 1:length(un)
                                                vid = find(app.inROI == un(j));
                                                tmp(mask(vid)) = oPth(j,jj);
                                            end
                                            niftiwrite(single(tmp),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff'],app.inBrainInfo);
                                            gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff.nii']);
                                            delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff.nii']);
                                            if app.MakebrainfigsButton.Value
                                                try
                                                    [tmp1,~,~,~,olim] = brainMontagerWithAtlas([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff.nii.gz'],[app.oPth s 'meanSubMap_GM.nii.gz'],app.inROIInfo.Filename,round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'viridis',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true,app.ExcludevoxelswithlessthanEditField_2.Value,'both');
                                                    saveas(gcf,[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff_' num2str(min(olim)) '_' num2str(max(olim))  '.fig']); close(tmp1{1}.Parent.Parent.Parent);
                                                catch
                                                end
                                            end

                                            tmp = zeros([app.inBrainInfo.raw.dim(2:4)]);
                                            for j = 1:length(un)
                                                vid = find(app.inROI == un(j));
                                                if oPall(j,jj) <= vthr
                                                    tmp(mask(vid)) = oPth(j,jj);
                                                else
                                                    tmp(mask(vid)) = 0;
                                                end
                                            end
                                            niftiwrite(single(tmp),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff_thresh_' num2str(vthr) '_Vox.nii'],app.inBrainInfo);
                                            gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff_thresh_' num2str(vthr) '_Vox.nii']);
                                            delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff_thresh_' num2str(vthr) '_Vox.nii']);
                                            if app.MakebrainfigsButton.Value
                                                try
                                                    [tmp1,~,~,~,olim] = brainMontagerWithAtlas([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff_thresh_' num2str(vthr) '_Vox.nii.gz'],[app.oPth s 'meanSubMap_GM.nii.gz'],app.inROIInfo.Filename,round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'viridis',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true,app.ExcludevoxelswithlessthanEditField_2.Value,'both');
                                                    saveas(gcf,[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff_thresh_' num2str(vthr) '_Vox_' num2str(min(olim)) '_' num2str(max(olim))  '.fig']); close(tmp1{1}.Parent.Parent.Parent);
                                                catch
                                                end
                                            end

                                            if strcmpi(p,'fdr')
                                                tmpp = mafdr(oPall(:,jj),'BHFDR',true);
                                                tmp = ones([app.inBrainInfo.raw.dim(2:4)]);
                                                for j = 1:length(un)
                                                    vid = find(app.inROI == un(j));
                                                    if tmpp(j) <= vthr
                                                        tmp(mask(vid)) = oPth(j,jj);
                                                    else
                                                        tmp(mask(vid)) = 0;
                                                    end
                                                end
                                                % tmpid = find(app.inROI == 0);
                                                % tmp(mask(tmpid)) = 0;

                                                niftiwrite(single(tmp),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff_thresh_corrected_' num2str(vthr) '_Vox.nii'],app.inBrainInfo);
                                                gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff_thresh_corrected_' num2str(vthr) '_Vox.nii']);
                                                delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff_thresh_corrected_' num2str(vthr) '_Vox.nii']);
                                                if app.MakebrainfigsButton.Value
                                                    try
                                                        [tmp1,~,~,~,olim] = brainMontagerWithAtlas([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff_thresh_corrected_' num2str(vthr) '_Vox.nii.gz'],[app.oPth s 'meanSubMap_GM.nii.gz'],app.inROIInfo.Filename,round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'viridis',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true,app.ExcludevoxelswithlessthanEditField_2.Value,'both');
                                                        saveas(gcf,[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff_thresh_corrected_' num2str(vthr) '_Vox_' num2str(min(olim)) '_' num2str(max(olim))  '.fig']); close(tmp1{1}.Parent.Parent.Parent);
                                                    catch
                                                    end
                                                end
                                            end
                                        end
                                    end
                                end
                            else
                                if strcmpi(ant,'mediation')
                                    for j = 1:size(oPallm,4)
                                        niftiwrite(single(squeeze(oPallm(:,:,:,j))),[app.oPth s app.PredictorListBox.Items{id1(i)} prnm(j) '_' ant '_' prnm{j} '_pval'],app.inBrainInfo);
                                        gzip([app.oPth s app.PredictorListBox.Items{id1(i)} prnm(j) '_' ant prnm{j} '_pval.nii']);
                                        delete([app.oPth s app.PredictorListBox.Items{id1(i)} prnm(j) '_' ant prnm{j} '_pval.nii']);

                                        niftiwrite(single(squeeze(oPthm(:,:,:,j))),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{j} '_coeff'],app.inBrainInfo);
                                        gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_path_coeff_values.nii']);
                                        delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_path_coeff_values.nii']);
                                        if app.MakebrainfigsButton.Value
                                            try
                                                [tmp1,~,~,~,olim] = brainMontagerWithAtlas([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_path_coeff_values.nii.gz'],[app.oPth s 'meanSubMap_GM.nii.gz'],app.inROIInfo.Filename,round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'viridis',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true,app.ExcludevoxelswithlessthanEditField_2.Value,'both');
                                                saveas(gcf,[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{jj} '_coeff_thresh_corrected_' num2str(vthr) '_Vox_' num2str(min(olim)) '_' num2str(max(olim))  '.fig']); close(tmp1{1}.Parent.Parent.Parent);
                                            catch
                                            end
                                        end
                                        niftiwrite(single(squeeze(oPthStdm(:,:,:,j))),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{j} 'coeff_standardized'],app.inBrainInfo);
                                        gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{j} 'coeff_standardized.nii']);
                                        delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{j} 'coeff_standardized.nii']);
                                        if app.MakebrainfigsButton.Value
                                            try
                                                [tmp1,~,~,~,olim] = brainMontagerWithAtlas([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{j} 'coeff_standardized.nii.gz'],[app.oPth s 'meanSubMap_GM.nii.gz'],app.inROIInfo.Filename,round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'viridis',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true,app.ExcludevoxelswithlessthanEditField_2.Value,'both');
                                                saveas(gcf,[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_' prnm{j} 'coeff_standardized_' num2str(min(olim)) '_' num2str(max(olim))  '.fig']); close(tmp1{1}.Parent.Parent.Parent);
                                            catch
                                            end
                                        end
                                    end
                                    niftiwrite(single(squeeze(oPm)),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_pval_medEffect'],app.inBrainInfo);
                                    gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_pval_medEffect.nii']);
                                    delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_pval_medEffect.nii']);

                                    niftiwrite(single(squeeze(oSm)),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect'],app.inBrainInfo);
                                    gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect.nii']);
                                    delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect.nii']);
                                    if app.MakebrainfigsButton.Value
                                        try
                                            [tmp1,~,~,~,olim] = brainMontagerWithAtlas([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect.nii.gz'],[app.oPth s 'meanSubMap_GM.nii.gz'],app.inROIInfo.Filename,round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'viridis',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true,app.ExcludevoxelswithlessthanEditField_2.Value,'both');
                                            saveas(gcf,[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect_' num2str(min(olim)) '_' num2str(max(olim))  '.fig']); close(tmp1{1}.Parent.Parent.Parent);
                                        catch
                                        end
                                    end

                                    niftiwrite(single(squeeze(oT)),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant 'tOrz_raw'],app.inBrainInfo);
                                    gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant 'tOrz_raw.nii']);
                                    delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant 'tOrz_raw.nii']);
                                    if app.MakebrainfigsButton.Value
                                        try
                                            [tmp1,~,~,~,olim] = brainMontagerWithAtlas([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant 'tOrz_raw.nii.gz'],[app.oPth s 'meanSubMap_GM.nii.gz'],app.inROIInfo.Filename,round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'viridis',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true,app.ExcludevoxelswithlessthanEditField_2.Value,'both');
                                            saveas(gcf,[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant 'tOrz_raw_' num2str(min(olim)) '_' num2str(max(olim))  '.fig']); close(tmp1{1}.Parent.Parent.Parent);
                                        catch
                                        end
                                    end
                                else
                                    niftiwrite(single(squeeze(oPm)),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_pval'],app.inBrainInfo);
                                    gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_pval_medEffect.nii']);
                                    delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_pval_medEffect.nii']);

                                    niftiwrite(single(squeeze(oSm)),[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat'],app.inBrainInfo);
                                    gzip([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect.nii']);
                                    delete([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect.nii']);
                                    if app.MakebrainfigsButton.Value
                                        try
                                            [tmp1,~,~,~,olim] = brainMontagerWithAtlas([app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect.nii.gz'],[app.oPth s 'meanSubMap_GM.nii.gz'],app.inROIInfo.Filename,round(linspace(1,app.inBrainInfo.raw.dim(4),20)),1000,[],'viridis',[],[1 1 1],false,1000,[],'gray',[],0.8,'ud',true,false,true,app.ExcludevoxelswithlessthanEditField_2.Value,'both');
                                            saveas(gcf,[app.oPth s app.PredictorListBox.Items{id1(i)} '_' ant '_stat_medEffect_' num2str(min(olim)) '_' num2str(max(olim))  '.fig']); close(tmp1{1}.Parent.Parent.Parent);
                                        catch
                                        end
                                    end
                                end
                            end
                        end
                    end
                end
                app.inBrain = brainBackup;
                app.inBrain2 = brainBackup2;
                app.inBeh =  behBackup;
                app.inROI = roiBackup;
            catch
                uialert(app.gBATUIFigure,'Something went wrong...unknown error. Try resetting loaded in data. You may have run out of RAM (e.g., check task manager)','Data missing');
                app.inBrain = brainBackup;
                app.inBrain2 = brainBackup2;
                app.inBeh =  behBackup;
                app.inROI = roiBackup;
            end
        end

        % Button pushed function: ResetsettingsButton
        function reset(app, event)
            buttons = app.AnalysistorunButtonGroup.Buttons;
            buttonNames = arrayfun(@(btn) btn.Text, buttons, 'UniformOutput', false);
            id = find(ismember(lower(buttonNames),lower(app.defaults.analysisType)));
            app.AnalysistorunButtonGroup.Buttons(id).Value = true;

            buttons = app.RelationshipstoconsiderButtonGroup.Buttons;
            buttonNames = arrayfun(@(btn) btn.Text, buttons, 'UniformOutput', false);
            id = find(ismember(lower(buttonNames),lower(app.defaults.relation)));
            app.RelationshipstoconsiderButtonGroup.Buttons(id).Value = true;

            buttons = app.TailsforanalysisButtonGroup.Buttons;
            buttonNames = arrayfun(@(btn) btn.Text, buttons, 'UniformOutput', false);
            id = find(ismember(lower(buttonNames),lower(app.defaults.tail)));
            app.TailsforanalysisButtonGroup.Buttons(id).Value = true;

            buttons = app.OptionsforpvaluesandcorrectionsButtonGroup.Buttons;
            buttonNames = arrayfun(@(btn) btn.Text, buttons, 'UniformOutput', false);
            id = find(ismember(lower(buttonNames),lower(app.defaults.pCorr)));
            app.OptionsforpvaluesandcorrectionsButtonGroup.Buttons(id).Value = true;

            app.PredictorListBox.Value = app.PredictorListBox.Items{1};
            app.NuissanceListBox.Value = app.NuissanceListBox.Items{1};
            app.OutcomeListBox.Value = app.OutcomeListBox.Items{1};

            app.MakebrainfigsButton.Value = false;
            app.DropDown.Value = 'WM';

            % app.AtlasniftifileoptionalEditField.Value = [];
            % app.inAtlas = [];
            app.perms = app.defaults.nPerms;
            app.boots = app.defaults.nBoot;

            if app.defaults.bootP
                app.BiascorrectedbootstrappingButton.Value = true;
            else
                app.MediationtorzButton.Value = false;
            end

            if app.defaults.robustOLS
                app.UserobustOLSButton.Value = true;
            else
                app.UserobustOLSButton.Value = false;
            end

            % buttons = app.StattofocusonButtonGroup.Buttons;
            % buttonNames = arrayfun(@(btn) btn.Text, buttons, 'UniformOutput', false);
            % id = find(ismember(lower(buttonNames),lower(app.defaults.keepStat)));
            % app.StattofocusonButtonGroup.Buttons(id).Value = true;

            app.ThresholdforvoxelROIsignificancepEditField.Value = app.defaults.vThresh;
            app.ThresholdforclustersignificancepEditField.Value = app.defaults.cThresh;

            if app.defaults.tempSave
                app.SavetemporaryfilesButton.Value = true;
            else
                app.SavetemporaryfilesButton.Value = false;
            end
            if app.defaults.cpu
                app.CPUparallelizationButton.Value = true;
            else
                app.CPUparallelizationButton.Value = false;
            end
            if app.defaults.norm
                app.ZscoredataButton.Value = true;
            else
                app.ZscoredataButton.Value = false;
            end
            % if isempty(app.defaults.mask)
            %     app.LoadamaskButton.Value = false;
            %     app.brainMask = [];
            % end
            app.ExcludevoxelswithlessthanEditField.Value = app.defaults.propOverlap;
            app.ExcludevoxelswithlessthanEditField_2.Value = app.defaults.meanThresh;
            toolboxUpdateOptions(app)
        end

        % Value changed function: GeneratedatahistogramDropDown
        function histSelect(app, event)
            tmpdata = table2array(app.inBeh(:,app.GeneratedatahistogramDropDown.Value));
            try
                h = histfit(app.UIAxes,tmpdata,round(length(tmpdata)*0.04),'kernel');
                h(1).FaceColor = [0.8118    0.3176    0.4824];
                h(1).EdgeColor = [0.8118    0.3176    0.4824];
                h(2).Color = [0.1490    0.5686    0.5412];% [0.8118    0.3176    0.4824];
                h(2).LineWidth = 5;
                h(1).Parent.XLim = [floor(min(tmpdata)) ceil(max(tmpdata))];
                app.UIAxes.Box = 'off';
            catch
                try
                    h = histogram(app.UIAxes,tmpdata);
                    h.FaceColor = [0.8118    0.3176    0.4824];
                    h.EdgeColor = [0.8118    0.3176    0.4824];
                    h.FaceAlpha = 1;
                    h.Parent.XLim = [floor(min(tmpdata)) ceil(max(tmpdata))];
                    app.UIAxes.Box = 'off';
                catch
                    uialert(app.gBATUIFigure,'Could not plot your behavioral data--maybe these are subject numbers?','Plot error');
                end
            end
        end

        % Value changed function: PredictorListBox
        function predictorSelect(app, event)
            value = app.PredictorListBox.Value;

        end

        % Value changed function: NuissanceListBox
        function nuissSelect(app, event)
            value = app.NuissanceListBox.Value;

        end

        % Value changed function: OutcomeListBox
        function outcomeSelect(app, event)
            value = app.OutcomeListBox.Value;

        end

        % Callback function
        function keepStatMed(app, event)
            if app.StatstokeepButtonGroup.Buttons(3).Value % set default back for cluster correction...
                app.MediationtorzButton.Value = true;
            end
            toolboxUpdateOptions(app)

        end

        % Callback function
        function statClustCorr(app, event)
            toolboxUpdateOptions(app)

        end

        % Value changed function: ExcludevoxelswithlessthanEditField_2
        function preThresh(app, event)
            toolboxUpdateOptions(app)

        end

        % Menu selected function: ResetsettingsandallloadeddataMenu
        function resetAll(app, event)
            buttons = app.AnalysistorunButtonGroup.Buttons;
            buttonNames = arrayfun(@(btn) btn.Text, buttons, 'UniformOutput', false);
            id = find(ismember(lower(buttonNames),lower(app.defaults.analysisType)));
            app.AnalysistorunButtonGroup.Buttons(id).Value = true;

            buttons = app.RelationshipstoconsiderButtonGroup.Buttons;
            buttonNames = arrayfun(@(btn) btn.Text, buttons, 'UniformOutput', false);
            id = find(ismember(lower(buttonNames),lower(app.defaults.relation)));
            app.RelationshipstoconsiderButtonGroup.Buttons(id).Value = true;

            buttons = app.TailsforanalysisButtonGroup.Buttons;
            buttonNames = arrayfun(@(btn) btn.Text, buttons, 'UniformOutput', false);
            id = find(ismember(lower(buttonNames),lower(app.defaults.tail)));
            app.TailsforanalysisButtonGroup.Buttons(id).Value = true;

            buttons = app.OptionsforpvaluesandcorrectionsButtonGroup.Buttons;
            buttonNames = arrayfun(@(btn) btn.Text, buttons, 'UniformOutput', false);
            id = find(ismember(lower(buttonNames),lower(app.defaults.pCorr)));
            app.OptionsforpvaluesandcorrectionsButtonGroup.Buttons(id).Value = true;

            app.perms = app.defaults.nPerms;
            app.boots = app.defaults.nBoot;

            app.AtlasniftifileoptionalEditField.Value = '';
            app.BehavioraldatafileEditField.Value  = '';
            app.BraindatafileorfolderEditField.Value  = '';
            app.BehavioraldatafileEditField_2.Value  = '';
            app.AtlasniftifileoptionalEditField.Value = '';

            app.LoadROItissuedefinitionsButton.Value = false;
            app.DropDown.Value = 'WM';

            app.SecondBraindatafileorfolderEditField.Visible = 'off';
            app.SecondBraindatafileorfolderEditFieldLabel.Visible = 'off';
            app.LoadButton_4.Visible = 'off';
            app.DropDown.Visible = 'off';
            app.LoadROItissuedefinitionsButton.Visible = 'off';

            app.inBrain = [];
            app.inBrainInfo = [];
            app.inROIInfo = [];
            app.inBrainSubs = [];
            app.inBrainPth = [];
            app.inBeh = [];
            app.inBehSubs = [];
            app.inBehPth = [];
            app.inROI = [];
            app.inBrainPth2 = [];
            app.inROIPath = [];
            app.oPth = [];
            app.inBrain2 = [];
            app.inROIMetaData = [];
            app.brainMask = [];

            if app.defaults.bootP
                app.BiascorrectedbootstrappingButton.Value = true;
            else
                app.MediationtorzButton.Value = false;
            end

            if app.defaults.robustOLS
                app.UserobustOLSButton.Value = true;
            else
                app.UserobustOLSButton.Value = false;
            end

            % buttons = app.StattofocusonButtonGroup.Buttons;
            % buttonNames = arrayfun(@(btn) btn.Text, buttons, 'UniformOutput', false);
            % id = find(ismember(lower(buttonNames),lower(app.defaults.keepStat)));
            % app.StattofocusonButtonGroup.Buttons(id).Value = true;

            app.ThresholdforvoxelROIsignificancepEditField.Value = app.defaults.vThresh;
            app.ThresholdforclustersignificancepEditField.Value = app.defaults.cThresh;

            if app.defaults.tempSave
                app.SavetemporaryfilesButton.Value = true;
            else
                app.SavetemporaryfilesButton.Value = false;
            end
            if app.defaults.cpu
                app.CPUparallelizationButton.Value = true;
            else
                app.CPUparallelizationButton.Value = false;
            end
            if app.defaults.norm
                app.ZscoredataButton.Value = true;
            else
                app.ZscoredataButton.Value = false;
            end
            if isempty(app.defaults.mask)
                app.LoadamaskButton.Value = false;
                app.brainMask = [];
            end
            app.inBrain2 = [];
            app.inROIMetaData = [];
            app.MakebrainfigsButton.Value = false;

            app.ExcludevoxelswithlessthanEditField.Value = app.defaults.propOverlap;
            app.ExcludevoxelswithlessthanEditField_2.Value = app.defaults.meanThresh;
            toolboxUpdateOptions(app)
        end

        % Menu selected function: ResetallloadeddataMenu
        function resetData(app, event)
            app.AtlasniftifileoptionalEditField.Value = '';
            app.BehavioraldatafileEditField.Value  = '';
            app.BraindatafileorfolderEditField.Value  = '';
            app.BehavioraldatafileEditField_2.Value  = '';
            app.AtlasniftifileoptionalEditField.Value = '';

            app.LoadROItissuedefinitionsButton.Value = false;
            app.DropDown.Value = 'WM';

            app.SecondBraindatafileorfolderEditField.Visible = 'off';
            app.SecondBraindatafileorfolderEditFieldLabel.Visible = 'off';
            app.LoadButton_4.Visible = 'off';
            app.DropDown.Visible = 'off';
            app.LoadROItissuedefinitionsButton.Visible = 'off';

            app.inBrain = [];
            app.inBrainInfo = [];
            app.inROIInfo = [];
            app.inBrainSubs = [];
            app.inBrainPth = [];
            app.inBeh = [];
            app.inBehSubs = [];
            app.inBehPth = [];
            app.inROI = [];
            app.inBrainPth2 = [];
            app.inROIPath = [];
            app.oPth = [];
            app.inBrain2 = [];
            app.inROIMetaData = [];
            app.brainMask = [];

            if isempty(app.defaults.mask)
                app.LoadamaskButton.Value = false;
                app.brainMask = [];
            end
            app.PredictorListBox.Value = app.PredictorListBox.Items{1};
            app.NuissanceListBox.Value = app.NuissanceListBox.Items{1};
            app.OutcomeListBox.Value = app.OutcomeListBox.Items{1};

            toolboxUpdateOptions(app)
        end

        % Menu selected function: 
        % AddasecondsetofbrainmapsieforseparategreyandwhitemattermapsMenu
        function secondBrain(app, event)
            if isempty(app.inBrain)
                uialert(app.gBATUIFigure,'You cannot load a secondary batch of images without loading the primary set first!','Wrong order of operations');
            else
                if strcmpi(app.SecondBraindatafileorfolderEditField.Visible,'off')
                    app.SecondBraindatafileorfolderEditField.Visible = 'on';
                    app.SecondBraindatafileorfolderEditFieldLabel.Visible = 'on';
                    app.LoadButton_4.Visible = 'on';
                    app.DropDown.Visible = 'on';
                    app.LoadROItissuedefinitionsButton.Visible = 'on';
                else
                    app.SecondBraindatafileorfolderEditField.Visible = 'off';
                    app.SecondBraindatafileorfolderEditFieldLabel.Visible = 'off';
                    app.LoadButton_4.Visible = 'off';
                    app.DropDown.Visible = 'off';
                    app.LoadROItissuedefinitionsButton.Visible = 'off';
                end
                app.inBrain2 = [];
                app.inBrainSubs2 = [];
                app.inBrainPth2 = [];
                app.inROIMetaData = [];
                app.SecondBraindatafileorfolderEditField.Value = '';
            end
        end

        % Value changed function: SecondBraindatafileorfolderEditField
        function changeSecondBrainPath(app, event)
            f = app.SecondBraindatafileorfolderEditField.Value;
            if ~isempty(app.inBrain)
                if ~isempty(f)
                    s = filesep;
                    if isfolder(f)
                        d = dir([f s '*.nii*']);
                        app.inBrainPth2 = f;
                        if length(d) == 0
                            uialert(app.gBATUIFigure,'Could not find any nifti files in your directory.','Missing data');
                        else
                            app.inBrainPth2 = f;
                            sz = [];
                            p = uiprogressdlg(app.gBATUIFigure,'Title','Loading brain data',...
                                'Message','Starting up...','Cancelable','off');

                            for i = 1:length(d)
                                disp(['Working on loading file ' d(i).name ', which is file ' num2str(i) ' of ' num2str(length(d))])
                                p.Value = i/length(d);
                                p.Message = ['Working on loading file ' d(i).name ', which is file ' num2str(i) ' of ' num2str(length(d))];
                                [~,dd,ext] = fileparts(d(i).name);
                                if strcmpi(ext,'.nii')
                                    app.inBrainSubs2{i} = dd;
                                else
                                    [~,app.inBrainSubs2{i},~] = fileparts(dd);
                                end
                                tmpinfo = niftiinfo([d(i).folder s d(i).name]);
                                v = niftiread(tmpinfo);
                                if i == 1
                                    sz = size(v);
                                    app.inBrain2(i,:) = v(:);
                                else
                                    if sz == size(v)
                                        app.inBrain2(i,:) = v(:);
                                    else
                                        uialert(app.gBATUIFigure,'Some of the nifti files you have pointed to have different sizes. Please fix before continuing.','Data mismatch');
                                    end
                                end
                            end
                            delete(p)
                        end
                    else
                        id = strfind(f,'*');
                        if ~isempty(id)
                            d = dir(f);
                            if length(d) == 0
                                uialert(app.gBATUIFigure,'Could not find any nifti files in your directory based on your regular expression.','Missing data');
                            else
                                app.inBrainPth2 = f;
                                sz = [];
                                p = uiprogressdlg(app.gBATUIFigure,'Title','Loading brain data',...
                                    'Message','Starting up...','Cancelable','off');

                                for i = 1:length(d)
                                    disp(['Working on loading file ' d(i).name ', which is file ' num2str(i) ' of ' num2str(length(d))])
                                    p.Value = i/length(d);
                                    p.Message = ['Working on loading file ' d(i).name ', which is file ' num2str(i) ' of ' num2str(length(d))];
                                    [~,dd,ext] = fileparts(d(i).name);
                                    if strcmpi(ext,'.nii')
                                        app.inBrainSubs2{i} = dd;
                                    else
                                        [~,app.inBrainSubs2{i},~] = fileparts(dd);
                                    end
                                    tmpinfo = niftiinfo([d(i).folder s d(i).name]);
                                    v = niftiread(tmpinfo);
                                    if i == 1
                                        sz = size(v);
                                        app.inBrain2(i,:) = v(:);
                                    else
                                        if sz == size(v)
                                            app.inBrain2(i,:) = v(:);
                                        else
                                            uialert(app.gBATUIFigure,'Some of the nifti files you have pointed to have different sizes. Please fix before continuing.','Data mismatch');
                                        end
                                    end
                                end
                                delete(p)
                            end
                        else
                            [~,~,ext] = fileparts(f);
                            if strcmpi(ext,'.mat')
                                app.inBrainPth2 = f;
                                tmp = load(app.inBrainPth2);
                                list = fieldnames(tmp);
                                if length(list) > 1
                                    [idx,tf] = listdlg('ListString',list,'PromptString',{'Select variable from your mat file', 'that represent your brain','data'},'SelectionMode','single');
                                    if tf
                                        app.inBrain2 = tmp.(list{idx(1)});
                                    end

                                    [idx,tf] = listdlg('ListString',list,'PromptString',{'Now select variable from your mat file', 'that represent your sub','IDs'},'SelectionMode','single');
                                    if tf
                                        app.inBrainSubs2 = tmp.(list{idx(1)});
                                    end
                                else
                                    uialert(app.gBATUIFigure,'If loading brain data as mat file you must have separate subject name vector and brain matrix (i.e., at least 2 variables in the file)','Data missing');
                                end
                            else
                                app.BraindatafileorfolderEditField.Value = '';
                                uialert(app.gBATUIFigure,'Error parsing your path...please see instructions.','Path error');
                            end
                        end
                    end

                    % check if there is a mask loaded in first...if so, apply it
                    if ~isempty(app.brainMask)
                        if length(app.brainMask) > size(app.inBrain2,2)
                            uialert(app.gBATUIFigure,'Your already loaded in brain mask has a dimension mismatch. Removing the mask...','Data mismatch');
                            app.brainMask = [];
                            app.LoadamaskButton.Value = false;
                            if ~isempty(app.inROI)
                                uialert(app.gBATUIFigure,'You had an atlas loaded that worked with the mask but we assume the subject-level data is correct so we have removed the atlas as well','Data mismatch');
                                app.inROI = [];
                                app.inROIPath = [];
                                app.AtlasniftifileoptionalEditField.Value = '';
                            end
                            % else
                            %     app.inBrain = app.inBrain(mask,:);
                        end
                    end

                    % if an ROI has already been loaded in, let's convert...
                    if ~isempty(app.inROI)
                        % first, check ROI/input data availability
                        if isequal(length(app.inROI),size(app.inBrain2,2))


                        else
                            uialert(app.gBATUIFigure,'Your ROI and subject-level brain data have a dimension mismatch. Erasing ROI as we assume the subject-level data is correct','Data mismatch');
                            app.inROI = [];
                            app.inROIPath = [];
                            app.AtlasniftifileoptionalEditField.Value = '';
                        end
                    end

                    % check if inBrain2 and inBrain1 are same shape
                    if size(app.inBrain,2) ~= size(app.inBrain2,2)
                        uialert(app.gBATUIFigure,'Secondary brain data does not match primary brain data in size','Dimension mismatch');
                        app.inBrain2 = [];
                        app.inBrainPth2 = [];
                        app.inROIMetaData = [];
                        app.inBrainSubs2 = [];
                        app.SecondBraindatafileorfolderEditField.Value = '';
                    end

                else
                    app.inBrain2 = [];
                    app.inROIMetaData = [];
                    app.inBrainSubs2 = [];
                    app.inBrainPth2 = [];
                    %app.BraindatafileorfolderEditField.Value = '';
                end
            else
                uialert(app.gBATUIFigure,'You need to load primary brain data before you can load secondary brain data','Data mismatch');
            end
            toolboxUpdateOptions(app)
        end

        % Button pushed function: LoadButton_4
        function loadSecondBrain(app, event)
            if ~isempty(app.inBrain)
                [f,pth] = uigetfile({'*.nii;*.gz;*.mat'},[pwd],'Please select a file from the folder that contains all of the niftis you would like to analyze or a mat file that contains your extracted images');

                if sum(f) ~= 0
                    s = filesep;
                    f = [pth f];
                    [f1, ~, ext] = fileparts(f);

                    if strcmpi(ext,'.nii') || strcmpi(ext,'.gz')
                        app.inBrainPth2 = f1;
                        app.SecondBraindatafileorfolderEditField.Value = f1;
                        d = dir([app.inBrainPth2 s '*.nii*']);
                        sz = [];

                        p = uiprogressdlg(app.gBATUIFigure,'Title','Loading secondary brain data',...
                            'Message','Starting up...','Cancelable','off');

                        for i = 1:length(d)
                            disp(['Working on loading file ' d(i).name ', which is file ' num2str(i) ' of ' num2str(length(d))])
                            p.Value = i/length(d);
                            p.Message = ['Working on loading file ' d(i).name ', which is file ' num2str(i) ' of ' num2str(length(d))];
                            [~,dd,ext] = fileparts(d(i).name);
                            if strcmpi(ext,'.nii')
                                app.inBrainSubs2{i} = dd;
                            else
                                [~,app.inBrainSubs2{i},~] = fileparts(dd);
                            end
                            tmpinfo = niftiinfo([d(i).folder s d(i).name]);
                            v = niftiread(tmpinfo);
                            if i == 1
                                sz = size(v);
                                app.inBrain2(i,:) = v(:);
                            else
                                if sz == size(v)
                                    app.inBrain2(i,:) = v(:);
                                else
                                    uialert(app.gBATUIFigure,'Some of the nifti files you have pointed to have different sizes. Please fix before continuing.','Data mismatch');
                                end
                            end
                        end
                        delete(p)
                    elseif strcmpi(ext,'.mat')
                        app.inBrainPth2 = f;
                        app.SecondBraindatafileorfolderEditField.Value = f;
                        tmp = load(app.inBrainPth2);
                        list = fieldnames(tmp);
                        if length(list) > 1
                            [idx,tf] = listdlg('ListString',list,'PromptString',{'Select variable from your mat file', 'that represent your brain','data'},'SelectionMode','single');
                            if tf
                                app.inBrain2 = tmp.(list{idx(1)});
                            end

                            [idx,tf] = listdlg('ListString',list,'PromptString',{'Now select variable from your mat file', 'that represent your sub','IDs'},'SelectionMode','single');
                            if tf
                                app.inBrainSubs2 = tmp.(list{idx(1)});
                            end
                        else
                            uialert(app.gBATUIFigure,'If loading brain data as mat file you must have separate subject name vector and brain matrix (i.e., at least 2 variables in the file)','Data missing');
                        end
                    end
                end

                % check if inBrain2 and inBrain1 are same shape
                if size(app.inBrain,2) ~= size(app.inBrain2,2)
                    uialert(app.gBATUIFigure,'Secondary brain data does not match primary brain data in size','Dimension mismatch');
                    app.inBrain2 = [];
                    app.inBrainPth2 = [];
                    app.inBrainSubs2 = [];
                    app.inROIMetaData = [];
                    app.SecondBraindatafileorfolderEditField.Value = '';
                end

                % check if there is a mask loaded in first...if so, apply it
                if ~isempty(app.brainMask)
                    if max(app.brainMask) > length(app.inBrain2,1)
                        uialert(app.gBATUIFigure,'Your already loaded in brain mask has a dimension mismatch. Removing the mask...','Data mismatch');
                        app.brainMask = [];
                        app.LoadamaskButton.Value = false;
                        if ~isempty(app.inROI)
                            uialert(app.gBATUIFigure,'You had an atlas loaded that worked with the mask but we assume the subject-level data is correct so we have removed the atlas as well','Data mismatch');
                            app.inROI = [];
                            app.inROIPath = [];
                            app.AtlasniftifileoptionalEditField.Value = [];
                        end
                        % else
                        %     app.inBrain = app.inBrain(mask,:);
                    end
                end

                % if an ROI has already been loaded in, let's convert...
                if ~isempty(app.inROI)
                    % first, check ROI/input data availability
                    if isequal(size(app.inROI,2),size(app.inBrain2,2))


                    else
                        uialert(app.gBATUIFigure,'Your ROI and subject-level brain data have a dimension mismatch. Erasing ROI as we assume the subject-level data is correct','Data mismatch');
                        app.inROI = [];
                        app.inROIPath = [];
                        app.AtlasniftifileoptionalEditField.Value  = '';
                    end
                end
            else
                uialert(app.gBATUIFigure,'You need to load primary brain data before you can load secondary brain data','Data mismatch');
            end
            toolboxUpdateOptions(app)
        end

        % Value changed function: LoadROItissuedefinitionsButton
        function roiTissueDef(app, event)
            press = app.LoadROItissuedefinitionsButton.Value;
            if press
                [f,~] = uigetfile({'*.csv;*.txt;*.xlsx;*.xls;*.dat;*.dlm;*.tab;*.mat'},'Please select your atlas tissue data');
                if f ~= 0
                    [~,~,ext] = fileparts(f);
                    if ~strcmpi(ext,'.mat')
                        app.inROIMetaData = readtable(f);
                    else
                        tmp = load(f);
                        list = fieldnames(tmp);
                        if length(list) > 1
                            [idx,tf] = listdlg('ListString',list,'PromptString',{'Select variable from your mat file', 'that represent your atlas tissue','data'},'SelectionMode','single');
                        elseif length(list) == 1
                            idx = 1;
                            tf = true;
                        end
                        if tf
                            app.inROIMetaData = tmp.(list{idx(1)});
                            if ~istable(app.inROIMetaData)
                                app.inROIMetaData = array2table(app.inROIMetaData);
                            end
                        end
                    end
                end
            else
                app.inROIMetaData = [];
            end
        end

        % Value changed function: BootstrappvaluesButton_2
        function bootSelect2(app, event)
            if app.BootstrappvaluesButton_2.Value
                answer = inputdlg('How many bootstraps do you want to perform?','Define permutations',[1 50],{num2str(app.defaults.nBoot)});
                if ~isempty(answer)
                    app.boots = str2double(answer);
                end
            end
            toolboxUpdateOptions(app)
        end

        % Callback function
        function statFocus(app, event)
            selectedButton = app.StattofocusonButtonGroup.SelectedObject;
            toolboxUpdateOptions(app);
        end

        % Menu selected function: FDRMenu
        function fdrMulti(app, event)
            uialert(app.gBATUIFigure,'A menu will pop up, please select all of the brain files (NIFTIs) you would like to FDR correct together','Data missing',"Icon","warning");
            [infiles,infolders] = uigetfile({'*.nii;*.nii.gz'},'Select brain files to FDR together','Multiselect','on');
            if ~isempty(infiles)
                if ~iscell(infiles)
                    infiles = {infiles};
                end
                uialert(app.gBATUIFigure,'Another menu will pop up, if the brain files represent regional results, please select the atlas file that was used to define these regions, otherwise hit the cancel button on the new menu.','Import atlas',"Icon","warning");
               [atlasFile,atlasFolder] = uigetfile({'*.nii;*.nii.gz'},'Select atlas if applicable','Multiselect','off');
            end
            
            for i = 1:length(infiles)
                tmp = niftiread([infolders filesep infiles{i}]);
                maps(:,i) = tmp(:);
                if i == 1
                    info = niftiinfo([infolders filesep infiles{i}]);
                    sz = size(tmp);
                end
            end

            cont = true;
            if ischar(atlasFile)
                at = niftiread([atlasFolder filesep atlasFile]);
                un = setdiff(unique(at),0);
                for i = 1:length(un)
                    id = find(at == un(i));
                    tmp = unique(maps(id,:),'rows');
                    if all(isnan(tmp))
                        tmp = NaN;
                    end
                    if size(tmp,1) > 1
                        uialert(app.gBATUIFigure,"Your atlas file does not match your data, multiple unique values within a region.","Invalid Atlas File");
                        break
                        cont = false;
                    else
                        m(i,:) = tmp;
                    end
                end
                mfdr = mafdr(m(:),'BHFDR',true);
                mfdr = reshape(mfdr,size(m));

                mapsfdr = ones(size(maps));
                for i = 1:length(un)
                    id = find(at == un(i));
                    for j = 1:size(mapsfdr,2)
                        mapsfdr(id,j) = mfdr(i,j);
                    end
                end
            else
                uialert(app.gBATUIFigure,'Another menu will pop up since you are correcting voxelwise data. Please provide a binary mask where 1s represent the voxels you want to correct (e.g., the brain vs empty space).','Import mask',"Icon","warning");
                [maskFile,maskFolder] = uigetfile({'*.nii;*.nii.gz'},'Select atlas if applicable','Multiselect','off');
                if ischar(maskFile)
                    tmp = niftiread([maskFolder filesep maskFile]);
                    mask = find(tmp == 1);
                else
                    uialert(app.gBATUIFigure,'The mask was not loaded...correcting across all voxels, potentially ones you never intended to look at as well','Import mask');
                    mask = 1:size(maps,1);
                end
                mapsfdr = ones(size(maps));
                mapsfdrtmp = maps(mask,:);
                mapsfdrtmp2 = mafdr(mapsfdrtmp(:),'BHFDR',true);
                mapsfdrtmp2 = reshape(mapsfdrtmp2,size(mapsfdrtmp));
                mapsfdr(mask,:) = mapsfdrtmp2;
            end
            if cont
                 for i = 1:length(infiles)
                     info = niftiinfo([infolders filesep infiles{i}]);
                     [~,nm,ext] = fileparts(infiles{i});
                     if strcmpi(ext,'.gz')
                         nm =nm(1:end-4);
                     end
                     niftiwrite(single(reshape(mapsfdr(:,i),sz)),[infolders filesep nm '_FDRcorrtog'],info);
                     gzip([infolders filesep nm '_FDRcorrtog.nii']);
                     delete([infolders filesep nm '_FDRcorrtog.nii'])
                 end
            end
        end

        % Menu selected function: BonferroniMenu
        function bonfMulti(app, event)
            uialert(app.gBATUIFigure,'A menu will pop up, please select all of the brain files (NIFTIs) you would like to Bonf. correct together (at alpha 0.05)','Data missing',"Icon","warning");
            [infiles,infolders] = uigetfile({'*.nii;*.nii.gz'},'Select brain files to Bonf. corr. together','Multiselect','on');
            if ~isempty(infiles)
                if ~iscell(infiles)
                    infiles = {infiles};
                end
                uialert(app.gBATUIFigure,'Another menu will pop up, if the brain files represent regional results, please select the atlas file that was used to define these regions, otherwise hit the cancel button on the new menu.','Import atlas',"Icon","warning");
               [atlasFile,atlasFolder] = uigetfile({'*.nii;*.nii.gz'},'Select atlas if applicable','Multiselect','off');
            end
            
            for i = 1:length(infiles)
                tmp = niftiread([infolders filesep infiles{i}]);
                maps(:,i) = tmp(:);
                if i == 1
                    info = niftiinfo([infolders filesep infiles{i}]);
                    sz = size(tmp);
                end
            end

            cont = true;
            if ischar(atlasFile)
                at = niftiread([atlasFolder filesep atlasFile]);
                un = setdiff(unique(at),0);
                for i = 1:length(un)
                    id = find(at == un(i));
                    tmp = unique(maps(id,:),'rows');
                    if all(isnan(tmp))
                        tmp = NaN;
                    end
                    if size(tmp,1) > 1
                        uialert(app.gBATUIFigure,"Your atlas file does not match your data, multiple unique values within a region.","Invalid Atlas File");
                        break
                        cont = false;
                    else
                        m(i,:) = tmp;
                    end
                end
                mfdr = bonf_holm(m(:),0.05);
                mfdr = reshape(mfdr,size(m));

                mapsfdr = ones(size(maps));
                for i = 1:length(un)
                    id = find(at == un(i));
                    for j = 1:size(mapsfdr,2)
                        mapsfdr(id,j) = mfdr(i,j);
                    end
                end
            else
                uialert(app.gBATUIFigure,'Another menu will pop up since you are correcting voxelwise data. Please provide a binary mask where 1s represent the voxels you want to correct (e.g., the brain vs empty space).','Import mask',"Icon","warning");
                [maskFile,maskFolder] = uigetfile({'*.nii;*.nii.gz'},'Select atlas if applicable','Multiselect','off');
                if ischar(maskFile)
                    tmp = niftiread([maskFolder filesep maskFile]);
                    mask = find(tmp == 1);
                else
                    uialert(app.gBATUIFigure,'The mask was not loaded...correcting across all voxels, potentially ones you never intended to look at as well','Import mask');
                    mask = 1:size(maps,1);
                end
                mapsfdr = ones(size(maps));
                mapsfdrtmp = maps(mask,:);
                mapsfdrtmp2 = bonf_holm(mapsfdrtmp(:),0.05);
                mapsfdrtmp2 = reshape(mapsfdrtmp2,size(mapsfdrtmp));
                mapsfdr(mask,:) = mapsfdrtmp2;
            end
            if cont
                 for i = 1:length(infiles)
                     info = niftiinfo([infolders filesep infiles{i}]);
                     [~,nm,ext] = fileparts(infiles{i});
                     if strcmpi(ext,'.gz')
                         nm =nm(1:end-4);
                     end
                     niftiwrite(single(reshape(mapsfdr(:,1),sz)),[infolders filesep nm '_Bonfcorrtog'],info);
                     gzip([infolders filesep nm '_Bonfcorrtog.nii']);
                     delete([infolders filesep nm '_Bonfcorrtog.nii'])
                 end
            end
        end

        % Menu selected function: RegionalMenu
        function regionalFS(app, event)
            tmp = which('brainSurfer.mlapp');
            if isempty(tmp)
                uialert(app.gBATUIFigure,'Brainsurfer not found, please download brainsurfer to project results onto the surface','Missing code');
            else
                uialert(app.gBATUIFigure,'Another menu will pop up, please select the thresholded regional data in volume space that you would like to project (you will later be asked to provide a version of the atlas you used, both in volume and in surface space)','Project volume results',"Icon","warning");
                [volFile,volFolder] = uigetfile({'*.nii;*.nii.gz'},'Select thresholded regional volume map','Multiselect','off');
                if ischar(volFolder)
                   uialert(app.gBATUIFigure,'One more menu will pop up, please select the version of the atlas you used for the analysis (volume space atlas)','Project volume results',"Icon","warning");
                   [atlasFile,atlasFolder] = uigetfile({'*.nii;*.nii.gz'},'Select atlas in volume space','Multiselect','off');
                   if ischar(volFolder)
                       uialert(app.gBATUIFigure,'One last menu will pop up, please select the same atlas projected onto fsaverage surface space (both hemispheres so two files)','Project volume results',"Icon","warning");
                       [atlasFile2,atlasFolder2] = uigetfile({'*.nii;*.nii.gz'},'Select atlas in surface space (both left and right files)','Multiselect','on');
                       if length(atlasFile2) > 1
                           atlasVol2Surf([volFolder volFile],[atlasFolder atlasFile],[atlasFolder2 atlasFile2{1}],[atlasFolder2 atlasFile2{2}]);
                       else
                           uialert(app.gBATUIFigure,['See the same directory of your input volume file for results'],"Icon","warning");
                       end
                   end
                end
            end
        end

        % Menu selected function: VoxelwiseMenu
        function voxFS(app, event)
             tmp = which('brainSurfer.mlapp');
            if isempty(tmp)
                uialert(app.gBATUIFigure,'Brainsurfer not found, please download brainsurfer to project results onto the surface','Missing code');
            else
                uialert(app.gBATUIFigure,'Another menu will pop up, please select the MNI volume file that you would like to project (it should contain continuous values and we expect it to have a threshold already applied)','Project atlas',"Icon","warning");
                [atlasFile,atlasFolder] = uigetfile({'*.nii;*.nii.gz'},'Select volume file you would like to project','Multiselect','off');
                if ischar(atlasFile)
                    tmpOut = convertMNI2FSWithMask([atlasFolder atlasFile]);
                    uialert(app.gBATUIFigure,['See: ' tmpOut{1}],'Projected thresholded map',"Icon","warning");
                end
            end
        end

        % Menu selected function: ConvertVolumeAtlasToFsaverageMenu
        function convertAtlas(app, event)
            tmp = which('brainSurfer.mlapp');
            if isempty(tmp)
                uialert(app.gBATUIFigure,'Brainsurfer not found, please download brainsurfer to project results onto the surface','Missing code');
            else
                uialert(app.gBATUIFigure,'Another menu will pop up, please select the MNI atlas file that you would like to project','Project atlas',"Icon","warning");
                [atlasFile,atlasFolder] = uigetfile({'*.nii;*.nii.gz'},'Select atlas','Multiselect','off');
                if ischar(atlasFile)
                    tmpOut = convertROIForFS([atlasFolder atlasFile], []);
                    uialert(app.gBATUIFigure,['See: ' tmpOut{1}],'Projected atlas',"Icon","warning");
                end
            end
        end

        % Menu selected function: 
        % MakeNewAtlasBasedOnRemovedROIsduringanalysisMenu
        function newAtlas(app, event)
            uialert(app.gBATUIFigure,'A menu will pop up asking you to load a csv file output by gBAT when performing an analysis (*_analysis_ROI_results.csv)','Load results csv');
            [csvFile,csvFolder] = uigetfile({'*.csv'},'Select the *_analysis_ROI_results.csv file output by gBAT','Multiselect','off');
            if ischar(csvFile)
                tab = readtable([csvFolder filesep csvFile]);
                uialert(app.gBATUIFigure,'A menu will pop up asking you to load the atlas file used in the analysis','Load atlas volume');
                [atlasFile,atlasFolder] = uigetfile({'*.nii;*.nii.gz'},'Select atlas','Multiselect','off');
                if ischar(atlasFile)
                    info = niftiinfo([atlasFolder filesep atlasFile]);
                    m = niftiread([atlasFolder filesep atlasFile]);
                    id = find(~ismember(m,table2array(tab(:,1))));
                    m(id) = 0;
                    [~,nm,ext] = fileparts(atlasFile);
                    if strcmpi(ext,'.gz')
                        nm =nm(1:end-4);
                    end
                    niftiwrite(m,[atlasFolder filesep nm '_removedMissingROIsInAnalysis'],info)
                    gzip([atlasFolder filesep nm '_removedMissingROIsInAnalysis.nii'])
                    delete([atlasFolder filesep nm '_removedMissingROIsInAnalysis.nii'])
                    uialert(app.gBATUIFigure,['See: ' atlasFolder filesep nm '_removedMissingROIsInAnalysis'],'Trimmed atlas',"Icon","warning");
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.gBATUIFigure = uifigure('Visible', 'off');
            app.gBATUIFigure.Name = 'gBAT';
            app.PostprocessingMenu = uimenu(app.gBATUIFigure);
            app.PostprocessingMenu.Text = 'Postprocessing';
            app.MakeNewAtlasBasedOnRemovedROIsduringanalysisMenu = uimenu(app.PostprocessingMenu);
            app.MakeNewAtlasBasedOnRemovedROIsduringanalysisMenu.MenuSelectedFcn = createCallbackFcn(app, @newAtlas, true);
            app.MakeNewAtlasBasedOnRemovedROIsduringanalysisMenu.Tooltip = {'This button will take the atlas you used in a gBAT analysis and remove the ROIs that were not analyzed (use this atlas file for multiple comparisons correction)'};
            app.MakeNewAtlasBasedOnRemovedROIsduringanalysisMenu.Text = 'Make New Atlas Based On Removed ROIs during analysis';
            app.MultipleComparisonCorrectionAcrossAnalysesMenu = uimenu(app.PostprocessingMenu);
            app.MultipleComparisonCorrectionAcrossAnalysesMenu.Tooltip = {'These options will let you perform FDR correction, either in order to change the threshold after you run an analysis in gBAT, or to make corrections based on multiple maps together (gBAT corrects for multiple comparisons within a brain map by default)'};
            app.MultipleComparisonCorrectionAcrossAnalysesMenu.Text = 'Multiple Comparison Correction Across Analyses';
            app.FDRMenu = uimenu(app.MultipleComparisonCorrectionAcrossAnalysesMenu);
            app.FDRMenu.MenuSelectedFcn = createCallbackFcn(app, @fdrMulti, true);
            app.FDRMenu.Text = 'FDR';
            app.BonferroniMenu = uimenu(app.MultipleComparisonCorrectionAcrossAnalysesMenu);
            app.BonferroniMenu.MenuSelectedFcn = createCallbackFcn(app, @bonfMulti, true);
            app.BonferroniMenu.Text = 'Bonferroni';
            app.ConvertVolumeAtlasToFsaverageMenu = uimenu(app.PostprocessingMenu);
            app.ConvertVolumeAtlasToFsaverageMenu.MenuSelectedFcn = createCallbackFcn(app, @convertAtlas, true);
            app.ConvertVolumeAtlasToFsaverageMenu.Tooltip = {'This will convert an atlas in volume space (MNI) to the fsaverage surface'};
            app.ConvertVolumeAtlasToFsaverageMenu.Text = 'Convert Volume Atlas To Fsaverage';
            app.ConvertVolumeResultsToFsaverageMenu = uimenu(app.PostprocessingMenu);
            app.ConvertVolumeResultsToFsaverageMenu.Tooltip = {'This will project a regional analysis output by gBAT to fsaverage surface space (you will need the atlas in both volume and fsaverage surface spaces)'};
            app.ConvertVolumeResultsToFsaverageMenu.Text = 'Convert Volume Results To Fsaverage';
            app.RegionalMenu = uimenu(app.ConvertVolumeResultsToFsaverageMenu);
            app.RegionalMenu.MenuSelectedFcn = createCallbackFcn(app, @regionalFS, true);
            app.RegionalMenu.Text = 'Regional';
            app.VoxelwiseMenu = uimenu(app.ConvertVolumeResultsToFsaverageMenu);
            app.VoxelwiseMenu.MenuSelectedFcn = createCallbackFcn(app, @voxFS, true);
            app.VoxelwiseMenu.Text = 'Voxelwise';
            app.AnalysistorunButtonGroup = uibuttongroup(app.gBATUIFigure);
            app.AnalysistorunButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @analysisSelection, true);
            app.AnalysistorunButtonGroup.Tooltip = {'How do we relate the behavioral to brain data?'};
            app.AnalysistorunButtonGroup.Title = 'Analysis to run:';
            app.CorrelationButton = uitogglebutton(app.AnalysistorunButtonGroup);
            app.CorrelationButton.Tooltip = {'Correlate brain data with a predictor selected in the bottom panel'};
            app.CorrelationButton.Text = 'Correlation';
            app.CorrelationButton.Value = true;
            app.PartialcorrelationButton = uitogglebutton(app.AnalysistorunButtonGroup);
            app.PartialcorrelationButton.Tooltip = {'Correlate brain data with a predictor selected in the bottom panel and one or more nuissance variables'};
            app.PartialcorrelationButton.Text = 'Partial correlation';
            app.MediationButton = uitogglebutton(app.AnalysistorunButtonGroup);
            app.MediationButton.Tooltip = {'Relate the predictor to the outcome variable by way of brain data as a mediator'};
            app.MediationButton.Text = 'Mediation';
            app.BehavioraldatafileLabel = uilabel(app.gBATUIFigure);
            app.BehavioraldatafileLabel.Text = 'Behavioral data file:';
            app.BehavioraldatafileEditField = uieditfield(app.gBATUIFigure, 'text');
            app.BehavioraldatafileEditField.ValueChangedFcn = createCallbackFcn(app, @changeBehPath, true);
            app.BehavioraldatafileEditField.Tooltip = {'Paste a path to a csv, xls, xlsx or any other file that we can load in (also supports dat dlm tab and mat files). IMPORTANT: first column should be subject ids and your niftis should contain the same ids somewhere in the filename'};
            app.BehavioraldatafileEditField.Placeholder = 'Path to csv/xls/xlsx/txt/data/dlm/tab/mat';
            app.LoadButton = uibutton(app.gBATUIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @loadBeh, true);
            app.LoadButton.Tooltip = {'When clicked please select one csv, xls, xlsx or any other file type that we can load in with readtable.m'};
            app.LoadButton.Text = 'Load';
            app.LoadButton_2 = uibutton(app.gBATUIFigure, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @loadBrain, true);
            app.LoadButton_2.Tooltip = {'When clicked please select a file from a directory, we will load all nifti files from that directory. Alternatively, select a mat file that contains a matrix with your data (2D with rows samples and cols as voxels)'};
            app.LoadButton_2.Text = 'Load';
            app.BraindatafileorfolderEditFieldLabel = uilabel(app.gBATUIFigure);
            app.BraindatafileorfolderEditFieldLabel.Text = 'Brain data file or folder:';
            app.BraindatafileorfolderEditField = uieditfield(app.gBATUIFigure, 'text');
            app.BraindatafileorfolderEditField.ValueChangedFcn = createCallbackFcn(app, @changeBrainPath, true);
            app.BraindatafileorfolderEditField.Tooltip = {'Paste in the path to a directory or mat file. We will load all niftis from the directory (nii or nii.gz) or a variable from the mat file. Can include a regular expression if directory (e.g., smw*.nii.gz). Right click for additional options'};
            app.BraindatafileorfolderEditField.Placeholder = 'Path to .nii, .nii.gz dir or .mat file';
            app.graphicalBrainAssociationToolgBATLabel = uilabel(app.gBATUIFigure);
            app.graphicalBrainAssociationToolgBATLabel.Text = 'graphical Brain Association Tool (gBAT)';
            app.RelationshipstoconsiderButtonGroup = uibuttongroup(app.gBATUIFigure);
            app.RelationshipstoconsiderButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @relationSelection, true);
            app.RelationshipstoconsiderButtonGroup.Tooltip = {'If  doing correlation or partial correlation we can choose to use a method that considers any statistical relationship between two variables (distance correlation and partial distance correlation). Note distance correlation takes much longer to run and you will likely run out of RAM if you are using voxels rather than ROIs!'};
            app.RelationshipstoconsiderButtonGroup.Title = 'Relationships to consider:';
            app.LinearButton = uitogglebutton(app.RelationshipstoconsiderButtonGroup);
            app.LinearButton.Tooltip = {'Linear correlation or partial correlation'};
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.LinearandNonlinearButton = uitogglebutton(app.RelationshipstoconsiderButtonGroup);
            app.LinearandNonlinearButton.Tooltip = {'Distance correlation or partial distance correlation'};
            app.LinearandNonlinearButton.Text = 'Linear and Nonlinear';
            app.TailsforanalysisButtonGroup = uibuttongroup(app.gBATUIFigure);
            app.TailsforanalysisButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @tailSelection, true);
            app.TailsforanalysisButtonGroup.Tooltip = {'You may want to focus on just positive or negative effects depending on which is more plausible. If focusing on one tail, we halve only the p-values for the corresponding effects, preserving the rest of the p-values as they are. Note, for distance correlation (lin + nonlin option) we only have positive values so you should only see an option for ''both'' which reflects no manipulation of p-values after a 2-tail test. Note, for bootstrapped p-values (non-bias corrected version), we focus on the corresponding tail of the null distribution instead. For bias corrected bootstrapping we can only look at both tails. If mediation analysis is performed with non bias corrected bootstrapping, we will only apply negative or positive tail to the ab path (other paths are two tailed).'};
            app.TailsforanalysisButtonGroup.Title = 'Tails for analysis:';
            app.BothButton_2 = uitogglebutton(app.TailsforanalysisButtonGroup);
            app.BothButton_2.Text = 'Both';
            app.BothButton_2.Value = true;
            app.NegativeButton = uitogglebutton(app.TailsforanalysisButtonGroup);
            app.NegativeButton.Text = 'Negative';
            app.PositiveButton = uitogglebutton(app.TailsforanalysisButtonGroup);
            app.PositiveButton.Text = 'Positive';
            app.AdditionaloptionsButtonGroup = uibuttongroup(app.gBATUIFigure);
            app.AdditionaloptionsButtonGroup.Title = 'Additional options:';
            app.CPUparallelizationButton = uibutton(app.AdditionaloptionsButtonGroup, 'state');
            app.CPUparallelizationButton.ValueChangedFcn = createCallbackFcn(app, @cpuParSelect, true);
            app.CPUparallelizationButton.Tooltip = {'Click to use parfor and leverage all your cpu cores. This may backfire if data is particularly high dimensional and take slightly longer instead.'};
            app.CPUparallelizationButton.Text = 'CPU parallelization';
            app.ZscoredataButton = uibutton(app.AdditionaloptionsButtonGroup, 'state');
            app.ZscoredataButton.ValueChangedFcn = createCallbackFcn(app, @zScoreSelect, true);
            app.ZscoredataButton.Tooltip = {'Normalize all of the variables entering the model by z-scoring them. Monitor the display output in matlab, if you get warnings it may be worth doing this (e.g., mediation analysis can fail with small values)'};
            app.ZscoredataButton.Text = 'Z-score data';
            app.LoadamaskButton = uibutton(app.AdditionaloptionsButtonGroup, 'state');
            app.LoadamaskButton.ValueChangedFcn = createCallbackFcn(app, @loadMask, true);
            app.LoadamaskButton.Tooltip = {'Any non-zero values in the mask are the voxels we will analyze. For ROIs, this means we will only average voxels within this mask for each ROI'};
            app.LoadamaskButton.Text = 'Load a mask';
            app.ExcludevoxelswithlessthanEditFieldLabel_2 = uilabel(app.AdditionaloptionsButtonGroup);
            app.ExcludevoxelswithlessthanEditFieldLabel_2.Text = 'Group-mean voxelwise threshold :';
            app.ExcludevoxelswithlessthanEditField_2 = uieditfield(app.AdditionaloptionsButtonGroup, 'numeric');
            app.ExcludevoxelswithlessthanEditField_2.Limits = [0 1];
            app.ExcludevoxelswithlessthanEditField_2.ValueChangedFcn = createCallbackFcn(app, @preThresh, true);
            app.ExcludevoxelswithlessthanEditField_2.Tooltip = {'We can remove some voxels from analysis by getting an average image across subjects and applying a threshold to it (will remove voxels with values greater than specified value from analysis). Note, if ROI analysis this will only apply to determine which voxels get averaged together within ROIs.'};
            app.ExcludevoxelswithlessthanEditFieldLabel = uilabel(app.AdditionaloptionsButtonGroup);
            app.ExcludevoxelswithlessthanEditFieldLabel.Text = 'Minimum subject overlap:';
            app.ExcludevoxelswithlessthanEditField = uieditfield(app.AdditionaloptionsButtonGroup, 'numeric');
            app.ExcludevoxelswithlessthanEditField.Limits = [0 1];
            app.ExcludevoxelswithlessthanEditField.ValueChangedFcn = createCallbackFcn(app, @propSelect, true);
            app.ExcludevoxelswithlessthanEditField.Tooltip = {'Remove voxels  from analysis based on the proportion of your subjects that show an above-zero value there. Will be applied before averaging by ROIs if performing an ROI analysis.'};
            app.MediationoptionsButtonGroup = uibuttongroup(app.gBATUIFigure);
            app.MediationoptionsButtonGroup.Title = 'Mediation options:';
            app.BiascorrectedbootstrappingButton = uibutton(app.MediationoptionsButtonGroup, 'state');
            app.BiascorrectedbootstrappingButton.ValueChangedFcn = createCallbackFcn(app, @bootSelect, true);
            app.BiascorrectedbootstrappingButton.Tooltip = {'By default we solve for the mediation model''s significance using the Sobel test. It''s fast but very conservative. Bootstrapping the p-value is slow but less conservative. With this button we will use mediation.m''s bias corrected bootstrap. Note, it has recently been recommended NOT to bias correct for mediation. Our bootstrap option in the p-value section does not bias correct but will take longer than mediation.m''s implementation.'};
            app.BiascorrectedbootstrappingButton.Text = 'Bias corrected bootstrapping';
            app.BiascorrectedbootstrappingButton.Value = true;
            app.UserobustOLSButton = uibutton(app.MediationoptionsButtonGroup, 'state');
            app.UserobustOLSButton.ValueChangedFcn = createCallbackFcn(app, @robustSelect, true);
            app.UserobustOLSButton.Tooltip = {'By default, we try to do robust linear regression for the mediation model (vs standard linear regression). This can add a lot of processing time so consider unticking if pressed for time. Robust OLS helps address outliers'};
            app.UserobustOLSButton.Text = 'Use robust OLS';
            app.UserobustOLSButton.Value = true;
            app.OptionsforpvaluesandcorrectionsButtonGroup = uibuttongroup(app.gBATUIFigure);
            app.OptionsforpvaluesandcorrectionsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @multCompSelect, true);
            app.OptionsforpvaluesandcorrectionsButtonGroup.Tooltip = {'We should correct for the number of tests that we are performing (and possible increase in type 1 errors if doing a single tail test)'};
            app.OptionsforpvaluesandcorrectionsButtonGroup.Title = 'Options for p-values and corrections:';
            app.NocorrectionButton = uitogglebutton(app.OptionsforpvaluesandcorrectionsButtonGroup);
            app.NocorrectionButton.Tooltip = {'DANGER, WILL ROBINSON! Click at your own peril'};
            app.NocorrectionButton.Text = 'No correction';
            app.CorrectionbypermutedclustersizeButton = uitogglebutton(app.OptionsforpvaluesandcorrectionsButtonGroup);
            app.CorrectionbypermutedclustersizeButton.Tooltip = {'If you click this another GUI will pop up asking you how many permutations you want to perform. We permute the predictor variable, rerun the whole analysis, apply our statistical threshold, then get the largest surviving cluster size. This distribution of clusters is used to understand the size of cluster that is significant. Obviously this is not available for ROI analyses.'};
            app.CorrectionbypermutedclustersizeButton.Text = 'Correction by permuted cluster size';
            app.FDRButton = uitogglebutton(app.OptionsforpvaluesandcorrectionsButtonGroup);
            app.FDRButton.Tooltip = {'FDR uses mafdr.m with BHFDR set to true for generally more reasonable corrected values. This is the fastest correction option.'};
            app.FDRButton.Text = 'FDR';
            app.FDRButton.Value = true;
            app.ThresholdforvoxelROIsignificancepEditFieldLabel = uilabel(app.OptionsforpvaluesandcorrectionsButtonGroup);
            app.ThresholdforvoxelROIsignificancepEditFieldLabel.Text = 'Threshold for voxel/ROI significance: p < ';
            app.ThresholdforvoxelROIsignificancepEditField = uieditfield(app.OptionsforpvaluesandcorrectionsButtonGroup, 'text');
            app.ThresholdforvoxelROIsignificancepEditField.ValueChangedFcn = createCallbackFcn(app, @voxThreshSelect, true);
            app.ThresholdforvoxelROIsignificancepEditField.Tooltip = {'What should be our threshold for analysis significance? This is voxel or roi-level.'};
            app.ThresholdforvoxelROIsignificancepEditField.Placeholder = '0.05';
            app.SavetemporaryfilesButton = uibutton(app.OptionsforpvaluesandcorrectionsButtonGroup, 'state');
            app.SavetemporaryfilesButton.ValueChangedFcn = createCallbackFcn(app, @tempFileSelect, true);
            app.SavetemporaryfilesButton.Tooltip = {'If clicked, we will save out permutations as we go in case something crashes. Then maybe we can salvage it (note, you will have to call the vbm.m function yourself to do that)'};
            app.SavetemporaryfilesButton.Text = 'Save temporary files';
            app.ThresholdforclustersignificancepEditFieldLabel = uilabel(app.OptionsforpvaluesandcorrectionsButtonGroup);
            app.ThresholdforclustersignificancepEditFieldLabel.Text = 'Threshold for cluster significance: p < ';
            app.ThresholdforclustersignificancepEditField = uieditfield(app.OptionsforpvaluesandcorrectionsButtonGroup, 'text');
            app.ThresholdforclustersignificancepEditField.ValueChangedFcn = createCallbackFcn(app, @clustThreshSelect, true);
            app.ThresholdforclustersignificancepEditField.Tooltip = {'What should be the clusterforming significance threshold?'};
            app.ThresholdforclustersignificancepEditField.Placeholder = '0.05';
            app.BootstrappvaluesButton_2 = uibutton(app.OptionsforpvaluesandcorrectionsButtonGroup, 'state');
            app.BootstrappvaluesButton_2.ValueChangedFcn = createCallbackFcn(app, @bootSelect2, true);
            app.BootstrappvaluesButton_2.Tooltip = {'If clicked, we will bootstrap the p-values (without bias correction; this is for example recommended for mediation analysis over bias correction). Note, all p-value thresholds applied to bootstrapped p-values if clicked (including permutation analysis)'};
            app.BootstrappvaluesButton_2.Text = 'Bootstrap p-values';
            app.AdditionalargumentstopassButtonGroup = uibuttongroup(app.gBATUIFigure);
            app.AdditionalargumentstopassButtonGroup.Tooltip = {'Some of the functions we use support advanced features that you can add here as arguments. Note, we already do ''rows'',''pairwise'' for correlation and partial correlation this is just an example in the placeholder text. Some options you add here may break this package!'};
            app.AdditionalargumentstopassButtonGroup.Title = 'Additional arguments to pass:';
            app.EditField = uieditfield(app.AdditionalargumentstopassButtonGroup, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @addArgs, true);
            app.EditField.Placeholder = 'for corr.m, partialcorr.m, bcdistcorr.m, pdcPerm.m, or mediation.m (e.g., rows,pairwise for corr.m or perhaps plots for mediation.m)';
            app.LoadButton_3 = uibutton(app.gBATUIFigure, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @loadAtlas, true);
            app.LoadButton_3.Tooltip = {'This is optional for ROI-based VBM. When clicked please select one .nii or .nii.gz file that contains an atlas (i.e., a brain map of integers corresponding to ROIs)'};
            app.LoadButton_3.Text = 'Load';
            app.AtlasniftifileoptionalLabel = uilabel(app.gBATUIFigure);
            app.AtlasniftifileoptionalLabel.Text = 'Atlas nifti file (optional):';
            app.AtlasniftifileoptionalEditField = uieditfield(app.gBATUIFigure, 'text');
            app.AtlasniftifileoptionalEditField.ValueChangedFcn = createCallbackFcn(app, @changeAtlasPath, true);
            app.AtlasniftifileoptionalEditField.Tooltip = {'This is optional for ROI-based VBM. Paste your path to one .nii or .nii.gz file that contains an atlas (i.e., a brain map of integers corresponding to ROIs)'};
            app.AtlasniftifileoptionalEditField.Placeholder = 'Path to .nii or .nii.gz';
            app.DefinedatatotestPanel = uipanel(app.gBATUIFigure);
            app.DefinedatatotestPanel.Title = 'Define data to test:';
            app.UIAxes = uiaxes(app.DefinedatatotestPanel);
            xlabel(app.UIAxes, 'Behavior')
            ylabel(app.UIAxes, 'Density')
            zlabel(app.UIAxes, 'Z')
            app.GeneratedatahistogramDropDownLabel = uilabel(app.DefinedatatotestPanel);
            app.GeneratedatahistogramDropDownLabel.Text = 'Generate data histogram:';
            app.GeneratedatahistogramDropDown = uidropdown(app.DefinedatatotestPanel);
            app.GeneratedatahistogramDropDown.Items = {'Column 1', 'Column 2', 'Column 3'};
            app.GeneratedatahistogramDropDown.ValueChangedFcn = createCallbackFcn(app, @histSelect, true);
            app.GeneratedatahistogramDropDown.Tooltip = {'Plot one of the columns in your spreadsheet (behavioral data). Columns titled based on how readtable.m parses your column names'};
            app.GeneratedatahistogramDropDown.Value = 'Column 1';
            app.PredictorListBoxLabel = uilabel(app.DefinedatatotestPanel);
            app.PredictorListBoxLabel.Text = 'Predictor';
            app.PredictorListBox = uilistbox(app.DefinedatatotestPanel);
            app.PredictorListBox.Items = {'Column 1', 'Column 2', 'Column 3'};
            app.PredictorListBox.ValueChangedFcn = createCallbackFcn(app, @predictorSelect, true);
            app.PredictorListBox.Tooltip = {'Select one predictor (titles based on how readtable.m parses your spreadsheet columns)'};
            app.PredictorListBox.Value = 'Column 1';
            app.OutcomeListBoxLabel = uilabel(app.DefinedatatotestPanel);
            app.OutcomeListBoxLabel.Text = 'Outcome';
            app.OutcomeListBox = uilistbox(app.DefinedatatotestPanel);
            app.OutcomeListBox.Items = {'Column 1', 'Column 2', 'Column 3'};
            app.OutcomeListBox.Multiselect = 'on';
            app.OutcomeListBox.ValueChangedFcn = createCallbackFcn(app, @outcomeSelect, true);
            app.OutcomeListBox.Tooltip = {'Select one or more outcome variables (titles based on how readtable.m parses your spreadsheet columns). If more than one selection, we will run independent analyses for each outcome'};
            app.OutcomeListBox.Value = {'Column 1'};
            app.NuissanceListBoxLabel = uilabel(app.DefinedatatotestPanel);
            app.NuissanceListBoxLabel.Text = 'Nuissance';
            app.NuissanceListBox = uilistbox(app.DefinedatatotestPanel);
            app.NuissanceListBox.Items = {'Column 1', 'Column 2', 'Column 3'};
            app.NuissanceListBox.Multiselect = 'on';
            app.NuissanceListBox.ValueChangedFcn = createCallbackFcn(app, @nuissSelect, true);
            app.NuissanceListBox.Tooltip = {'Select one or more nuissance variables to control for (titles based on how readtable.m parses your spreadsheet columns). If you have no nuissance variables, please select the first column which should correspond to your subject IDs'};
            app.NuissanceListBox.Value = {'Column 1'};
            app.StartButton = uibutton(app.DefinedatatotestPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @run, true);
            app.StartButton.Text = 'Start';
            app.ResetsettingsButton = uibutton(app.DefinedatatotestPanel, 'push');
            app.ResetsettingsButton.ButtonPushedFcn = createCallbackFcn(app, @reset, true);
            app.ResetsettingsButton.Tooltip = {'Reset all settings. Right click to reset only data, or to reset data and settings'};
            app.ResetsettingsButton.Text = 'Reset settings';
            app.Lamp = uilamp(app.DefinedatatotestPanel);
            app.Lamp.Tooltip = {'Red means we are doing voxelwise, blue means ROI. We apply a lot of different masks. Sometimes the buttons you press will undo settings.'};
            app.MakebrainfigsButton = uibutton(app.DefinedatatotestPanel, 'state');
            app.MakebrainfigsButton.Text = 'Make brain figs';
            app.DirectoryforstoringoutputsLabel = uilabel(app.gBATUIFigure);
            app.DirectoryforstoringoutputsLabel.Text = 'Directory for storing outputs:';
            app.BehavioraldatafileEditField_2 = uieditfield(app.gBATUIFigure, 'text');
            app.BehavioraldatafileEditField_2.ValueChangedFcn = createCallbackFcn(app, @changeOutPath, true);
            app.BehavioraldatafileEditField_2.Tooltip = {'Path to a directory (which does not have to exist) where we will output our results'};
            app.BehavioraldatafileEditField_2.Placeholder = 'Path to output dir';
            app.SelectButton = uibutton(app.gBATUIFigure, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @loadOut, true);
            app.SelectButton.Tooltip = {'When clicked will open a gui to select an existing directory  into which we want to output our results'};
            app.SelectButton.Text = 'Select';
            app.LoadButton_4 = uibutton(app.gBATUIFigure, 'push');
            app.LoadButton_4.ButtonPushedFcn = createCallbackFcn(app, @loadSecondBrain, true);
            app.LoadButton_4.Visible = 'off';
            app.LoadButton_4.Tooltip = {'When clicked please select a file from a directory, we will load all nifti files from that directory. Alternatively, select a mat file that contains a matrix with your data (2D with rows samples and cols as voxels). Paste in the path to a directory or mat file. We will load all niftis from the directory (nii or nii.gz) or a variable from the mat file. Can include a regular expression if directory (e.g., smw*.nii.gz). This is a secondary batch of niftis we will use because we assume you want to pull GM and WM values separately for different ROIs'};
            app.LoadButton_4.Text = 'Load';
            app.SecondBraindatafileorfolderEditFieldLabel = uilabel(app.gBATUIFigure);
            app.SecondBraindatafileorfolderEditFieldLabel.Visible = 'off';
            app.SecondBraindatafileorfolderEditFieldLabel.Text = 'Second Brain data file or folder:';
            app.SecondBraindatafileorfolderEditField = uieditfield(app.gBATUIFigure, 'text');
            app.SecondBraindatafileorfolderEditField.ValueChangedFcn = createCallbackFcn(app, @changeSecondBrainPath, true);
            app.SecondBraindatafileorfolderEditField.Visible = 'off';
            app.SecondBraindatafileorfolderEditField.Tooltip = {'Paste in the path to a directory or mat file. We will load all niftis from the directory (nii or nii.gz) or a variable from the mat file. Can include a regular expression if directory (e.g., smw*.nii.gz). This is a secondary batch of niftis we will use because we assume you want to pull GM and WM values separately for different ROIs'};
            app.SecondBraindatafileorfolderEditField.Placeholder = 'Path to .nii, .nii.gz dir or .mat file';
            app.DropDown = uidropdown(app.gBATUIFigure);
            app.DropDown.Items = {'WM', 'GM'};
            app.DropDown.Visible = 'off';
            app.DropDown.Tooltip = {'You need to make sure to specify whether the secondary batch of files is gm or wm'};
            app.DropDown.Value = 'WM';
            app.LoadROItissuedefinitionsButton = uibutton(app.gBATUIFigure, 'state');
            app.LoadROItissuedefinitionsButton.ValueChangedFcn = createCallbackFcn(app, @roiTissueDef, true);
            app.LoadROItissuedefinitionsButton.Visible = 'off';
            app.LoadROItissuedefinitionsButton.Tooltip = {'You will need to load in a text or spreadsheet file where the first column contains ROI indices that match your atlas and the last column defines whether the ROIs are in GM or WM (1 for GM, 2 for WM, any other values will be treated as ROIs you DO NOT want to analyze)'};
            app.LoadROItissuedefinitionsButton.Text = 'Load ROI tissue definitions';
            app.ContextMenu = uicontextmenu(app.gBATUIFigure);
            app.ResetsettingsandallloadeddataMenu = uimenu(app.ContextMenu);
            app.ResetsettingsandallloadeddataMenu.MenuSelectedFcn = createCallbackFcn(app, @resetAll, true);
            app.ResetsettingsandallloadeddataMenu.Text = 'Reset settings and all loaded data';
            app.ResetallloadeddataMenu = uimenu(app.ContextMenu);
            app.ResetallloadeddataMenu.MenuSelectedFcn = createCallbackFcn(app, @resetData, true);
            app.ResetallloadeddataMenu.Text = 'Reset all loaded data';
            % Assign app.ContextMenu
            app.ResetsettingsButton.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.gBATUIFigure);
            app.AddasecondsetofbrainmapsieforseparategreyandwhitemattermapsMenu = uimenu(app.ContextMenu2);
            app.AddasecondsetofbrainmapsieforseparategreyandwhitemattermapsMenu.MenuSelectedFcn = createCallbackFcn(app, @secondBrain, true);
            app.AddasecondsetofbrainmapsieforseparategreyandwhitemattermapsMenu.Tooltip = {'Click this to get access to another loading option. If you load in a second set of images we assume you want to do an ROI analysis and your ROIs are in WM and GM so you want to pull mean values from different sets of maps. Note, you will have to load in your atlas labels or any text or spreadsheet file that contains 2 columns. The first column contains the index of each ROI in  an atlas file. The last column contains a 1 if the ROI is GM, 2 if the ROI is WM. All other values will be ignored.'};
            app.AddasecondsetofbrainmapsieforseparategreyandwhitemattermapsMenu.Text = 'Add a second set of brain maps (i.e., for separate grey and white matter maps)';
            % Assign app.ContextMenu2
            app.LoadButton_2.ContextMenu = app.ContextMenu2;
            app.BraindatafileorfolderEditField.ContextMenu = app.ContextMenu2;
            % Show the figure after all components are created
            app.gBATUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gBATGui

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.gBATUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.gBATUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.gBATUIFigure)
        end
    end
end